classdef TSADPlatform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SettingsMenu                    matlab.ui.container.Menu
        ThresholdsMenu                  matlab.ui.container.Menu
        MainPanel                       matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        DataPanel                       matlab.ui.container.Panel
        D_ImportCustomDatasetEditField  matlab.ui.control.EditField
        D_ImportCustomDatasetLabel      matlab.ui.control.Label
        D_BrowseCustomDatasetButton     matlab.ui.control.Button
        D_SelectedFileTestDropDown      matlab.ui.control.DropDown
        SelectedFileLabel_2             matlab.ui.control.Label
        D_SelectedChannelTestDropDown   matlab.ui.control.DropDown
        SelectedChannelLabel_2          matlab.ui.control.Label
        D_SelectedFileTrainDropDown     matlab.ui.control.DropDown
        SelectedFileLabel               matlab.ui.control.Label
        D_SelectedChannelTrainDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel            matlab.ui.control.Label
        D_NextButton                    matlab.ui.control.Button
        D_LoadDataButton                matlab.ui.control.Button
        D_RawTestingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        D_RawTrainingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        PreprocessingTab                matlab.ui.container.Tab
        PreprocessingPanel              matlab.ui.container.Panel
        P_RatioValTestLabel             matlab.ui.control.Label
        P_SelectedFileValTestDropDown   matlab.ui.control.DropDown
        SelectedFileLabel_5             matlab.ui.control.Label
        P_SelectedChannelValTestDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_5          matlab.ui.control.Label
        P_SelectedFileTestDropDown      matlab.ui.control.DropDown
        SelectedFileLabel_4             matlab.ui.control.Label
        P_SelectedChannelTestDropDown   matlab.ui.control.DropDown
        SelectedChannelLabel_4          matlab.ui.control.Label
        P_SelectedFileTrainDropDown     matlab.ui.control.DropDown
        SelectedFileLabel_3             matlab.ui.control.Label
        P_SelectedChannelTrainDropDown  matlab.ui.control.DropDown
        SelectedChannelLabel_3          matlab.ui.control.Label
        P_SplitTestSetCheckBox          matlab.ui.control.CheckBox
        P_RatioValTestEditField         matlab.ui.control.NumericEditField
        P_StandardDeviationLabel        matlab.ui.control.Label
        P_MeanLabel                     matlab.ui.control.Label
        P_MinimumLabel                  matlab.ui.control.Label
        P_ParametersLabel               matlab.ui.control.Label
        P_MaximumLabel                  matlab.ui.control.Label
        P_MethodButtonGroup             matlab.ui.container.ButtonGroup
        P_RescaleButton                 matlab.ui.control.RadioButton
        P_StandardizeButton             matlab.ui.control.RadioButton
        P_RawDataButton                 matlab.ui.control.RadioButton
        P_PreviousButton                matlab.ui.control.Button
        P_NextButton                    matlab.ui.control.Button
        P_PreprocessedValTestDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        P_PreprocessedTestingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        P_PreprocessedTrainingDatasetPreviewUIAxes  matlab.ui.control.UIAxes
        ModelTrainingTab                matlab.ui.container.Tab
        ModelTrainingPanel              matlab.ui.container.Panel
        MT_LoadFromFileButton           matlab.ui.control.Button
        MT_OptimizeAllButton            matlab.ui.control.Button
        MT_TrainAllButton               matlab.ui.control.Button
        MT_ModelPreviewLabel            matlab.ui.control.Label
        MT_PreviewPanel                 matlab.ui.container.Panel
        NoModelLoadedforPreviewLabel    matlab.ui.control.Label
        MT_PreviewOptionsPanel          matlab.ui.container.Panel
        MT_PreviewContentLabel_11       matlab.ui.control.Label
        MT_PreviewContentLabel_10       matlab.ui.control.Label
        MT_PreviewContentLabel_9        matlab.ui.control.Label
        MT_PreviewContentLabel_8        matlab.ui.control.Label
        MT_PreviewContentLabel_7        matlab.ui.control.Label
        MT_PreviewContentLabel_6        matlab.ui.control.Label
        MT_PreviewContentLabel_5        matlab.ui.control.Label
        MT_PreviewContentLabel_4        matlab.ui.control.Label
        MT_PreviewContentLabel_3        matlab.ui.control.Label
        MT_PreviewContentLabel_2        matlab.ui.control.Label
        MT_PreviewContentLabel          matlab.ui.control.Label
        MT_PreviewNameLabel_11          matlab.ui.control.Label
        MT_PreviewNameLabel_10          matlab.ui.control.Label
        MT_PreviewNameLabel_9           matlab.ui.control.Label
        MT_PreviewNameLabel_8           matlab.ui.control.Label
        MT_PreviewNameLabel_6           matlab.ui.control.Label
        MT_PreviewNameLabel_7           matlab.ui.control.Label
        MT_PreviewNameLabel_5           matlab.ui.control.Label
        MT_PreviewNameLabel_4           matlab.ui.control.Label
        MT_PreviewNameLabel_3           matlab.ui.control.Label
        MT_PreviewNameLabel_2           matlab.ui.control.Label
        MT_PreviewNameLabel             matlab.ui.control.Label
        MT_PreviewUIAxes                matlab.ui.control.UIAxes
        MT_StatisticalModelsPanel       matlab.ui.container.Panel
        MT_S_AddAllModelsButton         matlab.ui.control.Button
        MT_S_OptimizeHyperparametersButton  matlab.ui.control.Button
        MT_S_TrainAllModelsButton       matlab.ui.control.Button
        MT_S_TrainSelectedModelsButton  matlab.ui.control.Button
        MT_S_ClearModelsButton          matlab.ui.control.Button
        MT_S_AddModelButton             matlab.ui.control.Button
        MT_S_ModelsListBox              matlab.ui.control.ListBox
        MT_ClassicMachineLearningPanel  matlab.ui.container.Panel
        MT_CML_AddAllModelsButton       matlab.ui.control.Button
        MT_CML_OptimizeHyperparametersButton  matlab.ui.control.Button
        MT_CML_TrainAllModelsButton     matlab.ui.control.Button
        MT_CML_TrainSelectedModelsButton  matlab.ui.control.Button
        MT_CML_ClearModelsButton        matlab.ui.control.Button
        MT_CML_AddModelButton           matlab.ui.control.Button
        MT_CML_ModelsListBox            matlab.ui.control.ListBox
        MT_DeepNeuralNetworksPanel      matlab.ui.container.Panel
        MT_DNN_ParallelCheckBox         matlab.ui.control.CheckBox
        MT_DNN_AddAllModelsButton       matlab.ui.control.Button
        MT_DNN_OptimizeHyperparametersButton  matlab.ui.control.Button
        MT_DNN_TrainAllModelsButton     matlab.ui.control.Button
        MT_DNN_TrainSelectedModelsButton  matlab.ui.control.Button
        MT_DNN_ClearModelsButton        matlab.ui.control.Button
        MT_DNN_AddModelsButton          matlab.ui.control.Button
        MT_DNN_ModelsListBox            matlab.ui.control.ListBox
        MT_PreviousButton               matlab.ui.control.Button
        MT_NextButton                   matlab.ui.control.Button
        DetectionEvaluationTab          matlab.ui.container.Tab
        DetectionEvaluationPanel        matlab.ui.container.Panel
        DE_DetectionPanel               matlab.ui.container.Panel
        DE_DetectionTabGroup            matlab.ui.container.TabGroup
        DE_PlotsTab                     matlab.ui.container.Tab
        DE_DynamicScoresLabel           matlab.ui.control.Label
        DE_StaticScoresLabel            matlab.ui.control.Label
        DE_ThresholdDropDown            matlab.ui.control.DropDown
        ThresholdDropDownLabel          matlab.ui.control.Label
        zrangeSliderLabel               matlab.ui.control.Label
        DE_ZRangeSlider                 matlab.ui.control.Slider
        WindowSizeSliderLabel           matlab.ui.control.Label
        DE_WindowSizeSlider             matlab.ui.control.Slider
        DE_DynamicThresholdingUITable   matlab.ui.control.Table
        DE_AnomalyPaddingSlider         matlab.ui.control.Slider
        AnomalyPaddingSliderLabel       matlab.ui.control.Label
        DE_StaticThresholdingUITable    matlab.ui.control.Table
        DE_StaticThresholdingLabel      matlab.ui.control.Label
        DE_DynamicThresholdingLabel     matlab.ui.control.Label
        DE_StaticThresholdingDataUIAxes  matlab.ui.control.UIAxes
        DE_DynamicThresholdingDataUIAxes  matlab.ui.control.UIAxes
        DE_DynamicThresholdingAnomalyScoresUIAxes  matlab.ui.control.UIAxes
        DE_StaticThresholdingAnomalyScoresUIAxes  matlab.ui.control.UIAxes
        DE_TableTab                     matlab.ui.container.Tab
        LegendLabel                     matlab.ui.control.Label
        ColumnwisemaximaLabel           matlab.ui.control.Label
        RowwisemaximaLabel              matlab.ui.control.Label
        DE_ExportTableButton            matlab.ui.control.Button
        DE_FullDetectionUITable         matlab.ui.control.Table
        DE_DetectionShownForLabel       matlab.ui.control.Label
        DE_RunDetectionForAllButton     matlab.ui.control.Button
        DE_RunDetectionForLabel         matlab.ui.control.Label
        DE_RunDetectionForSelectedButton  matlab.ui.control.Button
        DE_FaultyDataSelectionDropDown  matlab.ui.control.DropDown
        SelectfaultyDataLabel           matlab.ui.control.Label
        DE_TrainedModelsPanel           matlab.ui.container.Panel
        DE_SaveSelectedModelsButton     matlab.ui.control.Button
        DE_SortByDropDown               matlab.ui.control.DropDown
        SortbyLabel                     matlab.ui.control.Label
        DE_TrainedModelsListBox         matlab.ui.control.ListBox
        DE_NextButton                   matlab.ui.control.Button
        DE_PreviousButton               matlab.ui.control.Button
        AutoRunTab                      matlab.ui.container.Tab
        AutoRunPanel                    matlab.ui.container.Panel
        AR_SweepExportButton            matlab.ui.control.Button
        AR_SweepResultsLabel            matlab.ui.control.Label
        AR_SweepResultsUITable          matlab.ui.control.Table
        AR_DatasetSweepPanel            matlab.ui.container.Panel
        AR_RatioValTestEditField        matlab.ui.control.NumericEditField
        IterationsLabel_3               matlab.ui.control.Label
        AR_SweepUseModelsCheckBox       matlab.ui.control.CheckBox
        AR_SweepDatasetEditField        matlab.ui.control.EditField
        AR_SweepDatasetLabel            matlab.ui.control.Label
        AR_SweepBrowseButton            matlab.ui.control.Button
        AR_PreprocessingMethodDropDown  matlab.ui.control.DropDown
        BestFscoreThresholdLabel        matlab.ui.control.Label
        AR_UseFractionEditField         matlab.ui.control.NumericEditField
        FractionofDatasettouseEditFieldLabel  matlab.ui.control.Label
        AR_RunEvaluationButton          matlab.ui.control.Button
        DynamicSwitchTab                matlab.ui.container.Tab
        DynamicSwitchPanel              matlab.ui.container.Panel
        DS_ExportButton                 matlab.ui.control.Button
        DS_TrainingAndEvaluationPanel   matlab.ui.container.Panel
        DS_UseModelsCheckBox            matlab.ui.control.CheckBox
        DS_ScoreDropDown                matlab.ui.control.DropDown
        BestFscoreThresholdLabel_3      matlab.ui.control.Label
        DS_PreprocessingMethodDropDown  matlab.ui.control.DropDown
        BestFscoreThresholdLabel_2      matlab.ui.control.Label
        DS_EvaluationLamp               matlab.ui.control.Lamp
        DS_TrainLamp                    matlab.ui.control.Lamp
        DS_AutoLabelLamp                matlab.ui.control.Lamp
        DS_TrainClassifierButton        matlab.ui.control.Button
        DS_RunEvaluationsButton         matlab.ui.control.Button
        DS_AutoLabelDatasetButton       matlab.ui.control.Button
        DS_DatasetEditField             matlab.ui.control.EditField
        DS_DatasetLabel                 matlab.ui.control.Label
        DS_BrowseDatasetButton          matlab.ui.control.Button
        DS_ResultsLabel                 matlab.ui.control.Label
        DS_ResultsUITable               matlab.ui.control.Table
        MT_DNN_ContextMenu              matlab.ui.container.ContextMenu
        MT_DNN_DeleteMenu               matlab.ui.container.Menu
        MT_DNN_ShowModelParametersMenu  matlab.ui.container.Menu
        MT_CML_ContextMenu              matlab.ui.container.ContextMenu
        MT_CML_DeleteMenu               matlab.ui.container.Menu
        MT_CML_ShowModelParametersMenu  matlab.ui.container.Menu
        MT_S_ContextMenu                matlab.ui.container.ContextMenu
        MT_S_DeleteMenu                 matlab.ui.container.Menu
        MT_S_ShowModelParametersMenu    matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %% App Status Information        
        info_dataLoaded = false;            % True if data was loaded
        info_dataIsMultivariate = false;    % True if data is multivariate
        info_modelsTrained = false;         % True if models have been trained
        info_detectionRun = false;          % True if detection has been done
        info_optimizationRunning = false;   % True if an optimization process is running
        %%

        %% Data        
        filesTrain               % Array of filenames of the training dataset
        filesTestFull                % Array of filenames of the testing dataset
        filesTest
        filesValTest

        labelsTrain                  
        
        labelsTestFull              
        labelsTest                      % Split anomaly labels for testing data

        dataTrainRaw                    % Initial fault free raw data
        dataTestRaw                     % Initial faulty raw data

        dataTrain                       % Preprocessed fault free data
        dataTest                        % Preprocessed faulty data

        dataValTest                     % Anomalous validation data (already preprocessed)
        labelsValTest                   % Anomalous validation data labels

        timestampsTrain                 % Timestamps of training data
        timestampsTestFull
        timestampsTest                  % Timestamps of the testing data  

        %% Preprocessing
        maximum                         % Maximum value of training data
        minimum                         % Minimum value of training data

        mu                              % Mean of training data
        sigma                           % Standard deviation of training data
        %%

        %% Models
        trainedModels                   % Trained models struct
        %%

        %% Detection
        thresholds = ["bestFscorePointwise", ...
                        "topK", ...
                        "dynamic"];
        currentThreshold = 'bestFscorePointwise'
        currentAnomalyScores            % Currently shown anomaly scores in detection tab
        currentYTest                    % YTest for currently shown prediction
        currentLabels                   % Labels of currently shown prediction
        currentModelName                % Name of Model used for current prediction
        currentModelType                % Type of Model used for current prediction (DNN, CML, ...)
        currentOptions                  % Options of the Model currently selected for detection on the detection panel

        fullScores = table(["Composite F1 Score (Static)"; ...
                                    "Point-wise F1 Score (Static)"; ...
                                    "Event-wise F1 Score (Static)"; ...
                                    "Point-adjusted F1 Score (Static)"; ...
                                    "Composite F0.5 Score (Static)"; ...
                                    "Point-wise F0.5 Score (Static)"; ...
                                    "Event-wise F0.5 Score (Static)"; ...
                                    "Point-adjusted F0.5 Score (Static)"; ...
                                    "Point-wise Precision (Static)"; ...
                                    "Event-wise Precision (Static)"; ...
                                    "Point-adjusted Precision (Static)"; ...
                                    "Point-wise Recall (Static)"; ...
                                    "Event-wise Recall (Static)"; ...
                                    "Point-adjusted Recall (Static)"; ...
                                    "Composite F1 Score (Dynamic)"; ...
                                    "Point-wise F1 Score (Dynamic)"; ...
                                    "Event-wise F1 Score (Dynamic)"; ...
                                    "Point-adjusted F1 Score (Dynamic)"; ...
                                    "Composite F0.5 Score (Dynamic)"; ...
                                    "Point-wise F0.5 Score (Dynamic)"; ...
                                    "Event-wise F0.5 Score (Dynamic)"; ...
                                    "Point-adjusted F0.5 Score (Dynamic)"; ...
                                    "Point-wise Precision (Dynamic)"; ...
                                    "Event-wise Precision (Dynamic)"; ...
                                    "Point-adjusted Precision (Dynamic)"; ...
                                    "Point-wise Recall (Dynamic)"; ...
                                    "Event-wise Recall (Dynamic)"; ...
                                    "Point-adjusted Recall (Dynamic)"]);
        %%

        f
        
        %% Dynamic Switch
        ds_classifier                   % Classification network for the dynamic switch

        %% Popup Apps
        ModelSelectionApp_DNN           % Popup for selecting the Deep Neural Network models
        ModelSelectionApp_CML           % Popup for selecting the Classic Machine Learning models
        ModelSelectionApp_S             % Popup for selecting the Statistical models
        OptimizeParametersApp           % Popup for optimizing hyperparameters
        OptimizeAllApp                  % Pupup for optimizing all selected models
        ThresholdSelectionApp           % Pupup for selecting thresholds
        %%
    end
    
    properties (Access = public)
%         net % Network
%         modelInfo % Network options
%         dataTest % Test data for simulink
%         labels % Labels for simulink
    end
    
    methods (Access = private)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% App interaction and helper functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% Disable/Enable GUI functions

        function oldPointer = beginTask(app)
            oldPointer = get(app.UIFigure, 'pointer');

            app.DataPanel.Enable = 'off';
            app.PreprocessingPanel.Enable = 'off';
            app.ModelTrainingPanel.Enable = 'off';
            app.DetectionEvaluationPanel.Enable = 'off';
            app.AutoRunPanel.Enable = 'off';
            app.DynamicSwitchPanel.Enable = 'off';

            set(app.UIFigure, 'pointer', 'watch');
            drawnow;
        end
        
        function endTask(app, oldPointer)
            app.DataPanel.Enable = 'on';
            app.PreprocessingPanel.Enable = 'on';
            app.ModelTrainingPanel.Enable = 'on';
            app.DetectionEvaluationPanel.Enable = 'on';
            app.AutoRunPanel.Enable = 'on';
            app.DynamicSwitchPanel.Enable = 'on';

            set(app.UIFigure, 'pointer', oldPointer);
        end

%% Data
        
        function legendUIAxes(~, ~,evnt, UIAxes)
            lines = UIAxes.Children;
            
            click_locations = linspace(0,1,numel(lines)+1);
            
            line_num = discretize(evnt.IntersectionPoint(2),click_locations);
            
            relevant_line = lines(line_num);
            if strcmp(relevant_line.Visible,'on')
                relevant_line.Visible = 'off';
            else
                relevant_line.Visible = 'on';
            end            
        end

        function createPreviewLegend(app, dataset, fileIdx)
            switch dataset
                case 'train-raw'
                    axes = app.D_RawTrainingDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTrain{fileIdx, 1});
                case 'test-raw'
                    axes = app.D_RawTestingDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTestFull{fileIdx, 1});
                case 'train'
                    axes = app.P_PreprocessedTrainingDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTrain{fileIdx, 1});
                case 'test'
                    axes = app.P_PreprocessedTestingDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsTest{fileIdx, 1});
                case 'val-test'
                    axes = app.P_PreprocessedValTestDatasetPreviewUIAxes;
                    indices = getIndexes(app.labelsValTest{fileIdx, 1});
            end
            
            YLims = get(axes, 'YLim');
            limits = [];
            for i = 1:size(indices, 2)
                limits(1,i) = YLims(1);
                limits(2, i) = YLims(1);
                limits(3, i) = YLims(2);
                limits(4, i) = YLims(2);
            end
            
            if ~isempty(indices) && ~isempty(limits)
                patch(axes, indices, limits, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.3);
                legend(axes, "Data", "Anomalies");
            else
                legend(axes, "Data");
            end
        end   

        function highlightTableMaxima(~, UITable, type)
            scoreMatrix = UITable.Data{:, 2:end};

            if strcmp(type, 'rowwise') || strcmp(type, 'all')
                % Add bold highlight for rowise maxima
                styleBold = uistyle;
                styleBold.FontWeight = 'bold';
                
                rows_max_rowwise = [];
                columns_max_rowwise = [];
    
                for i = 1:size(scoreMatrix, 1)
                    [~, col] = find(scoreMatrix(i, :) == max(scoreMatrix(i, :), [], 2));
                    
                    for j = 1:numel(col)
                        rows_max_rowwise(end + 1, 1) = i;
                        columns_max_rowwise(end + 1, 1) = col(j) + 1;
                    end
                end
                
                if ~isempty(rows_max_rowwise) && ~isempty(columns_max_rowwise)
                    addStyle(UITable, styleBold, 'cell', [rows_max_rowwise, columns_max_rowwise]);
                end
            end

            if strcmp(type, 'columnwise') || strcmp(type, 'all')
                % Add green background highlight for columnwise maxima
                styleGreen = uistyle;
                styleGreen.BackgroundColor = '#86F2BC';
    
                rows_max_columnwise = [];
                columns_max_columnwise = [];
    
                for i = 1:size(scoreMatrix, 2)
                    [row, ~] = find(scoreMatrix(:, i) == max(scoreMatrix(:, i), [], 1));
                    
                    for j = 1:numel(row)
                        rows_max_columnwise(end + 1, 1) = row(j);
                        columns_max_columnwise(end + 1, 1) = i + 1;
                    end
                end
                
                if ~isempty(rows_max_columnwise) && ~isempty(columns_max_columnwise)
                    addStyle(UITable, styleGreen, 'cell', [rows_max_columnwise, columns_max_columnwise]);
                end
            end
        end

        function showModelPreview(app, options)
            app.NoModelLoadedforPreviewLabel.Visible = 'off';
            app.MT_PreviewOptionsPanel.Visible = 'off';
            cla(app.MT_PreviewUIAxes);
            
            allNameLabels = [app.MT_PreviewNameLabel, ...
                                app.MT_PreviewNameLabel_2, ...
                                app.MT_PreviewNameLabel_3, ...
                                app.MT_PreviewNameLabel_4, ...
                                app.MT_PreviewNameLabel_5, ...
                                app.MT_PreviewNameLabel_6, ...
                                app.MT_PreviewNameLabel_7, ...
                                app.MT_PreviewNameLabel_8, ...
                                app.MT_PreviewNameLabel_9, ...
                                app.MT_PreviewNameLabel_10, ...
                                app.MT_PreviewNameLabel_11];

            allContentLabels = [app.MT_PreviewContentLabel, ...
                                app.MT_PreviewContentLabel_2, ...
                                app.MT_PreviewContentLabel_3, ...
                                app.MT_PreviewContentLabel_4, ...
                                app.MT_PreviewContentLabel_5, ...
                                app.MT_PreviewContentLabel_6, ...
                                app.MT_PreviewContentLabel_7, ...
                                app.MT_PreviewContentLabel_8, ...
                                app.MT_PreviewContentLabel_9, ...
                                app.MT_PreviewContentLabel_10, ...
                                app.MT_PreviewContentLabel_11];

            for i = 1:numel(allNameLabels)
                allNameLabels(i).Text = "";
                allContentLabels(i).Text = "";
            end

            allNameLabels(1).Text = 'Model:';
            allContentLabels(1).Text = options.model;
            allNameLabels(2).Text = 'Type:';
            switch options.type
                case 'DNN'            
                    allContentLabels(2).Text = 'Deep Neural Network';
                case 'CML'            
                    allContentLabels(2).Text = 'Machine Learning';
                case 'S'            
                    allContentLabels(2).Text = 'Statistical';
            end
            current_index = 3;

            if isfield(options, 'modelType')
                allNameLabels(3).Text = 'Model Type:';
                allContentLabels(3).Text = options.modelType;
                current_index = 4;
            end

            if isfield(options, 'hyperparameters')
                if isfield(options.hyperparameters, 'model')
                    fields = fieldnames(options.hyperparameters.model);
                    for i = 1:numel(fields)
                        allNameLabels(current_index).Text = fields{i} + ":";
                        allContentLabels(current_index).Text = string(options.hyperparameters.model.(fields{i}).value);
                        current_index = current_index + 1;
                    end
                end
                if isfield(options.hyperparameters, 'data')
                    fields = fieldnames(options.hyperparameters.data);
                    for i = 1:numel(fields)
                        allNameLabels(current_index).Text = fields{i} + ":";
                        allContentLabels(current_index).Text = string(options.hyperparameters.data.(fields{i}).value);
                        current_index = current_index + 1;
                    end
                end
                if isfield(options.hyperparameters, 'training')
                    fields = fieldnames(options.hyperparameters.training);
                    for i = 1:numel(fields)
                        allNameLabels(current_index).Text = fields{i} + ":";
                        allContentLabels(current_index).Text = string(options.hyperparameters.training.(fields{i}).value);
                        current_index = current_index + 1;
                    end
                end    
            end
            
            if strcmp(options.type, 'DNN')
                graph = app.createModelGraphForPreview(options);
                plot(app.MT_PreviewUIAxes, graph);
            end

            app.MT_PreviewOptionsPanel.Visible = 'on';
        end

%% Toggle functions for disabling/enabling elements
        
        function toggleLamp(~, lamp, state)
            if state
                lamp.Color = 'green';
            else
                lamp.Color = '#cccccc';
            end
        end

        function toggleTrainingStatus(app, state)
            if state
                app.info_modelsTrained = true;
            else
                app.info_modelsTrained = false;
            end
        end
        
        function toggleTrainButtons_DNN(app, state)
            if state                
                app.MT_DNN_TrainAllModelsButton.Enable = 'on';
                app.MT_DNN_TrainSelectedModelsButton.Enable = 'on';
                app.MT_DNN_ParallelCheckBox.Enable = 'on';
            else
                app.MT_DNN_TrainAllModelsButton.Enable = 'off';
                app.MT_DNN_TrainSelectedModelsButton.Enable = 'off';
                app.MT_DNN_ParallelCheckBox.Enable = 'off';
            end
        end

        function toggleOptimizeButton_DNN(app, state)
            if state
                app.MT_DNN_OptimizeHyperparametersButton.Enable = 'on';
            else
                app.MT_DNN_OptimizeHyperparametersButton.Enable = 'off';
            end
        end

        function toggleTrainButtons_CML(app, state)
            if state
                app.MT_CML_TrainAllModelsButton.Enable = 'on';
                app.MT_CML_TrainSelectedModelsButton.Enable = 'on';
            else
                app.MT_CML_TrainAllModelsButton.Enable = 'off';
                app.MT_CML_TrainSelectedModelsButton.Enable = 'off';
            end
        end
        
        function toggleOptimizeButton_CML(app, state)
            if state
                app.MT_CML_OptimizeHyperparametersButton.Enable = 'on';
            else
                app.MT_CML_OptimizeHyperparametersButton.Enable = 'off';
            end
        end

        function toggleTrainButtons_S(app, state)
            if state
                app.MT_S_TrainAllModelsButton.Enable = 'on';
                app.MT_S_TrainSelectedModelsButton.Enable = 'on';
            else
                app.MT_S_TrainAllModelsButton.Enable = 'off';
                app.MT_S_TrainSelectedModelsButton.Enable = 'off';
            end
        end
        
        function toggleOptimizeButton_S(app, state)
            if state
                app.MT_S_OptimizeHyperparametersButton.Enable = 'on';
            else
                app.MT_S_OptimizeHyperparametersButton.Enable = 'off';
            end
        end

%% Model preview

        function graph = createModelGraphForPreview(~, options)
            layers = getLayers(options, 1, 1);
            a = layers.Connections;
            b = extractBefore(a.Source, '/');
            c = extractBefore(a.Destination, '/');
            for i = 1:size(a, 1)
                if ~isempty(b{i})
                    a.Source{i} = b{i};
                end
                if ~isempty(c{i})
                    a.Destination{i} = c{i};
                end
            end
            EndNodes = [a.Source a.Destination];
            nodeTable = table(EndNodes);
            graph = digraph(nodeTable);
        end

%% Model listboxes

        function [label, id] = getLabelForModel(~, model, listBox)
            label = strcat(model, '  (1)');
            id = strcat(model, '_1');
            id = replace(id, ' ', '_');
            id = replace(id, '-', '_');
            id = replace(id, '(', '');
            id = replace(id, ')', '');

            itemCounter = 0;
            for i = 1:length(listBox.Items)
                item = split(listBox.Items(i), '  ');
                if strcmp(model, item{1})
                    itemCounter = itemCounter + 1;
                end
            end
            if itemCounter > 0
                label = sprintf('%s  (%d)', model, itemCounter + 1);
                id = sprintf('%s_%d', model, itemCounter + 1);
                id = replace(id, ' ', '_');
                id = replace(id, '-', '_');
                id = replace(id, '(', '');
                id = replace(id, ')', '');
            end
        end

        function updateModelLabels(~, listBox)
            itemsLength = length(listBox.Items);
            newItems = cell(itemsLength, 1);
            newItemsData = struct('options', {});
            idx = 1;
            labelCounter = 0;
            updatedLabels = strings();

            for i = 1:itemsLength
                label = split(listBox.ItemsData(i).options.label, '  ');
                if ~ismember(label{1}, updatedLabels)
                    for j = 1:itemsLength
                        itemCmp = split(listBox.ItemsData(j).options.label, '  ');
                        if strcmp(label{1}, itemCmp{1})
                            newItems{idx} = sprintf('%s  (%d)', itemCmp{1}, labelCounter + 1);
                            newNetInfo = listBox.ItemsData(j);
                            newNetInfo.options.id = sprintf('%s_%d', itemCmp{1}, labelCounter + 1);
                            newNetInfo.options.id = replace(newNetInfo.options.id, ' ', '_');
                            newNetInfo.options.id = replace(newNetInfo.options.id, '-', '_');
                            newNetInfo.options.id = replace(newNetInfo.options.id, '(', '');
                            newNetInfo.options.id = replace(newNetInfo.options.id, ')', '');
                            newNetInfo.options.label = newItems{idx};
                            newItemsData(idx) = newNetInfo;
                            idx = idx + 1;
                            labelCounter = labelCounter + 1;
                        end
                    end
                    updatedLabels(end + 1) = label{1};
                    labelCounter = 0;
                end
            end
            listBox.Items = newItems;
            listBox.ItemsData = newItemsData;
        end
     
%% Detection

        function resetDetection(app)
            cla(app.DE_StaticThresholdingDataUIAxes);
            cla(app.DE_StaticThresholdingAnomalyScoresUIAxes);
            cla(app.DE_DynamicThresholdingDataUIAxes);
            cla(app.DE_DynamicThresholdingAnomalyScoresUIAxes);
            app.DE_StaticThresholdingUITable.Data = {};
            app.DE_DynamicThresholdingUITable.Data = {};
            app.currentAnomalyScores = [];
            app.currentLabels = [];
            app.currentModelName = "";
            app.currentModelType = "";
            app.currentYTest = [];
            app.info_detectionRun = false;
            app.fullScores = table(["Composite F1 Score (Static)"; ...
                                    "Point-wise F1 Score (Static)"; ...
                                    "Event-wise F1 Score (Static)"; ...
                                    "Point-adjusted F1 Score (Static)"; ...
                                    "Composite F0.5 Score (Static)"; ...
                                    "Point-wise F0.5 Score (Static)"; ...
                                    "Event-wise F0.5 Score (Static)"; ...
                                    "Point-adjusted F0.5 Score (Static)"; ...
                                    "Point-wise Precision (Static)"; ...
                                    "Event-wise Precision (Static)"; ...
                                    "Point-adjusted Precision (Static)"; ...
                                    "Point-wise Recall (Static)"; ...
                                    "Event-wise Recall (Static)"; ...
                                    "Point-adjusted Recall (Static)"; ...
                                    "Composite F1 Score (Dynamic)"; ...
                                    "Point-wise F1 Score (Dynamic)"; ...
                                    "Event-wise F1 Score (Dynamic)"; ...
                                    "Point-adjusted F1 Score (Dynamic)"; ...
                                    "Composite F0.5 Score (Dynamic)"; ...
                                    "Point-wise F0.5 Score (Dynamic)"; ...
                                    "Event-wise F0.5 Score (Dynamic)"; ...
                                    "Point-adjusted F0.5 Score (Dynamic)"; ...
                                    "Point-wise Precision (Dynamic)"; ...
                                    "Event-wise Precision (Dynamic)"; ...
                                    "Point-adjusted Precision (Dynamic)"; ...
                                    "Point-wise Recall (Dynamic)"; ...
                                    "Event-wise Recall (Dynamic)"; ...
                                    "Point-adjusted Recall (Dynamic)"]);
            app.fullScores.Properties.VariableNames = "Metric";
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% Data preparation functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% Data preprocessing

        function showPreprocParameters(app)
            if ~isempty(app.dataTrainRaw)
                numFiles = size(app.dataTrainRaw, 1);
                numChannels = size(app.dataTrainRaw{1, 1}, 2);
    
                maxima = zeros(numFiles, numChannels);
                minima = zeros(numFiles, numChannels);
                for i = 1:numFiles
                    maxima(i, :) =  max(app.dataTrainRaw{i, 1}, [], 1);
                    minima(i, :) =  min(app.dataTrainRaw{i, 1}, [], 1);
                end
                app.maximum = max(maxima, [], 1);
                app.minimum = min(minima, [], 1);


                fullData = [];
                for i = 1:size(app.dataTrainRaw, 1)
                    fullData = [fullData; app.dataTrainRaw{i, 1}];
                end
                [~, app.mu, app.sigma] = zscore(fullData, 0, 1);
            elseif ~isempty(app.dataTestRaw)
                numFiles = size(app.dataTestRaw, 1);
                numChannels = size(app.dataTestRaw{1, 1}, 2);
    
                maxima = zeros(numFiles, numChannels);
                minima = zeros(numFiles, numChannels);
                for i = 1:numFiles
                    maxima(i, :) =  max(app.dataTestRaw{i, 1}, [], 1);
                    minima(i, :) =  min(app.dataTestRaw{i, 1}, [], 1);
                end
                app.maximum = max(maxima, [], 1);
                app.minimum = min(minima, [], 1);

                
                fullData = [];
                for i = 1:size(app.dataTestRaw, 1)
                    fullData = [fullData; app.dataTestRaw{i, 1}];
                end
                [~, app.mu, app.sigma] = zscore(fullData, 0, 1);
            end

            maximum = app.maximum;
            minimum = app.minimum;
            mu = app.mu;
            sigma = app.sigma;

            if ~isempty(app.maximum)
                app.P_MaximumLabel.Text = sprintf('Maximum: %f', max(app.maximum));
            else
                app.P_MaximumLabel.Text = 'Maximum: -';
            end
            if ~isempty(app.minimum)
                app.P_MinimumLabel.Text = sprintf('Minimum: %f', min(app.minimum));
            else
                app.P_MinimumLabel.Text = 'Minimum: -';
            end
            if ~isempty(app.mu)
                app.P_MeanLabel.Text = sprintf('Mean: %f', mean(app.mu));
            else
                app.P_MeanLabel.Text = 'Mean: -';
            end
            if ~isempty(app.sigma)
                app.P_StandardDeviationLabel.Text = sprintf('Standard Deviation: %f', mean(app.sigma));
            else
                app.P_StandardDeviationLabel.Text = 'Standard Deviation: -';
            end
        end

        function plotLoadedData(app, dataset, fileIdx, chIdx)
            switch dataset
                case 'train-raw'
                    axes = app.D_RawTrainingDatasetPreviewUIAxes;
                    data = app.dataTrainRaw{fileIdx, 1}(:, chIdx);
                case 'test-raw'
                    axes = app.D_RawTestingDatasetPreviewUIAxes;
                    data = app.dataTestRaw{fileIdx, 1}(:, chIdx);
                case 'train'
                    axes = app.P_PreprocessedTrainingDatasetPreviewUIAxes;
                    data = app.dataTrain{fileIdx, 1}(:, chIdx);
                case 'test'
                    axes = app.P_PreprocessedTestingDatasetPreviewUIAxes;
                    data = app.dataTest{fileIdx, 1}(:, chIdx);
                case 'val-test'
                    axes = app.P_PreprocessedValTestDatasetPreviewUIAxes;
                    data = app.dataValTest{fileIdx, 1}(:, chIdx);
            end
            
            cla(axes);
            plot(axes, data);
%             l = legend(axes, Location='northeastoutside');
%             set(l, 'ButtonDownFcn', {@app.legendUIAxes, axes})
        end

        function updatePreviewData(app, dataset)
            switch dataset
                case 'train-raw'
                    file = app.D_SelectedFileTrainDropDown.Value;
                    [~, fileIdx] = ismember(file, app.D_SelectedFileTrainDropDown.Items);
                    chIdx = str2double(app.D_SelectedChannelTrainDropDown.Value);

                case 'test-raw'
                    file = app.D_SelectedFileTestDropDown.Value;
                    [~, fileIdx] = ismember(file, app.D_SelectedFileTestDropDown.Items);
                    chIdx = str2double(app.D_SelectedChannelTestDropDown.Value);
                case 'train'
                    file = app.P_SelectedFileTrainDropDown.Value;
                    [~, fileIdx] = ismember(file, app.P_SelectedFileTrainDropDown.Items);
                    chIdx = str2double(app.P_SelectedChannelTrainDropDown.Value);
                case 'test'
                    file = app.P_SelectedFileTestDropDown.Value;
                    [~, fileIdx] = ismember(file, app.P_SelectedFileTestDropDown.Items);
                    chIdx = str2double(app.P_SelectedChannelTestDropDown.Value);
                case 'val-test'
                    file = app.P_SelectedFileValTestDropDown.Value;
                    [~, fileIdx] = ismember(file, app.P_SelectedFileValTestDropDown.Items);
                    chIdx = str2double(app.P_SelectedChannelValTestDropDown.Value);
            end

            app.plotLoadedData(dataset, fileIdx, chIdx);
            app.createPreviewLegend(dataset, fileIdx);
        end
                
        function runPreprocessing(app, plotEnabled)
            preprocMethod = app.P_MethodButtonGroup.SelectedObject.Text;

            [app.dataTrain, app.dataTest, ~, ~, ~, ~] = preprocessData(app.dataTrainRaw, app.dataTestRaw, preprocMethod, false, []);
            
            if app.P_SplitTestSetCheckBox.Value
                ratio = app.P_RatioValTestEditField.Value;
            else
                ratio = 1;
            end

            [app.dataTest, ...
                app.labelsTest, ...
                app.dataValTest, ...
                app.labelsValTest, ...
                app.filesTest, ...
                app.filesValTest] = splitTestVal(app.dataTest, app.labelsTestFull, ratio, app.filesTestFull);

            app.P_SelectedFileTestDropDown.Items = app.filesTest;
            app.P_SelectedFileValTestDropDown.Items = app.filesValTest;

            app.DE_FaultyDataSelectionDropDown.Items = app.filesTest;
            
            if plotEnabled
                if ~isempty(app.dataTrain)
                    app.updatePreviewData('train');
                end
    
                if ~isempty(app.dataTest)
                    app.updatePreviewData('test');
                end

                if ~isempty(app.dataValTest)
                    app.P_SelectedChannelValTestDropDown.Enable = 'on';
                    app.P_SelectedFileValTestDropDown.Enable = 'on';

                    app.updatePreviewData('val-test');
                else
                    app.P_SelectedChannelValTestDropDown.Enable = 'off';
                    app.P_SelectedFileValTestDropDown.Enable = 'off';

                    cla(app.P_PreprocessedValTestDatasetPreviewUIAxes);
                end
            end
        end

%% Training functions
        
        function trainDNNs(app, models)
            if ~app.P_SplitTestSetCheckBox.Value
                ratioValTest = 1;
            else
                ratioValTest = app.P_RatioValTestEditField.Value;
            end

            if app.MT_DNN_ParallelCheckBox.Value && ~app.info_dataIsMultivariate
                trainedModels_tmp = trainModels_DNN_Parallel(models, ...
                    app.dataTrain, ...
                    app.labelsTrain, ...
                    app.dataValTest, ...
                    app.labelsValTest, ...
                    ratioValTest, ...
                    app.thresholds, ...
                    false);
            else
                trainedModels_tmp = trainModels_DNN_Consecutive(models, ...
                    app.dataTrain, ...
                    app.labelsTrain, ...
                    app.dataValTest, ...
                    app.labelsValTest, ...
                    ratioValTest, ...
                    app.thresholds, ...
                    'training-progress');
            end
                
            fields = fieldnames(trainedModels_tmp);
            for i = 1:length(fields)
                app.fullScores.(fields{i}) = [NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                              NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                              NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN];
                app.trainedModels.(fields{i}) = trainedModels_tmp.(fields{i});
            end

            app.showTrainedModels();
        end
        

        function trainCMLs(app, models)
            if ~app.P_SplitTestSetCheckBox.Value
                ratioValTest = 1;
            else
                ratioValTest = app.P_RatioValTestEditField.Value;
            end

            trainedModels_tmp = trainModels_CML(models, ...
                app.dataTrain, ...
                app.dataValTest, ...
                app.labelsValTest, ...
                ratioValTest, ...
                app.thresholds);
            
            fields = fieldnames(trainedModels_tmp);
            for i = 1:length(fields)
                app.fullScores.(fields{i}) = [NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                              NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                              NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN];
                app.trainedModels.(fields{i}) = trainedModels_tmp.(fields{i});
            end
            
            app.showTrainedModels();
        end

        function trainSs(app,models)
            if ~app.P_SplitTestSetCheckBox.Value
                ratioValTest = 1;
            else
                ratioValTest = app.P_RatioValTestEditField.Value;
            end

            trainedModels_tmp = trainModels_S(models, ...
                app.dataTrain, ...
                app.dataValTest, ...
                app.labelsValTest, ...
                ratioValTest, ...
                app.thresholds);
            
            fields = fieldnames(trainedModels_tmp);
            for i = 1:length(fields)
                app.fullScores.(fields{i}) = [NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                              NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                              NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN];
                app.trainedModels.(fields{i}) = trainedModels_tmp.(fields{i});
            end
            
            app.showTrainedModels();
        end
%% Optimization functions

        function optimizeModel(app, options, optVars, iterations, cmpScore)
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Optimizing Model(s)',...
                Indeterminate='on');
            drawnow;

            app.info_optimizationRunning = true;
            exportLogData = true;

            switch cmpScore
                case 'Point-wise F1 Score'
                    threshold = "bestFscorePointwise";
                case 'Event-wise F1 Score'
                    threshold = "bestFscoreEventwise";
                case 'Point-adjusted F1 Score'
                    threshold = "bestFscorePointAdjusted";
                case 'Composite F1 Score'
                    threshold = "bestFscoreComposite";
            end
            
            if ~app.P_SplitTestSetCheckBox.Value
                ratioValTest = 1;
            else
                ratioValTest = app.P_RatioValTestEditField.Value;
            end
            
            results = optimizeModel(optVars, options, app.dataTrain, ...
                                    app.labelsTrain, app.dataValTest, app.labelsValTest, ...
                                    app.dataTest, app.labelsTest, ratioValTest, ...
                                    threshold, cmpScore, iterations, exportLogData);

            optimumVars = results.XAtMinObjective;
            optimumVarNames = optimumVars.Properties.VariableNames;
            msg = 'Optimal hyperparameters:';
            msg = sprintf('%s\n', msg);
            for i = 1:length(optimumVarNames)
                if ~isnumeric(optimumVars{1, i})
                    msg = sprintf('%s %s: %s\n', msg, optimumVarNames{i}, optimumVars{1, i});
                else
                    msg = sprintf('%s %s: %d\n', msg, optimumVarNames{i}, optimumVars{1, i});
                end
            end
            
            close(d);
            uialert(app.UIFigure, msg, 'Optimization finished', Icon='success');

            options = adaptModelOptions(options, optimumVars);
            options.id = options.id + '_optimized';
            options.label = options.model + ' (optimized)';
            modelInfo.options = options;
            
            switch options.modelType
                case 'DNN'
                    app.MT_DNN_ModelsListBox.Items = [app.MT_DNN_ModelsListBox.Items, options.label];
                    app.MT_DNN_ModelsListBox.ItemsData = [app.MT_DNN_ModelsListBox.ItemsData, modelInfo];
                case 'CML'
                    app.MT_CML_ModelsListBox.Items = [app.MT_CMLModelsListBox.Items, options.label];
                    app.MT_CML_ModelsListBox.ItemsData = [app.MT_CML_ModelsListBox.ItemsData, modelInfo];
                case 'S'
                    app.MT_S_ModelsListBox.Items = [app.MT_S_ModelsListBox.Items, options.label];
                    app.MT_S_ModelsListBox.ItemsData = [app.MT_S_ModelsListBox.ItemsData, modelInfo];
            end

            app.info_optimizationRunning = false;
            app.endTask(oldPointer);
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% Detection and evaluation functions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% General functions
                           
        function plotDynamicThresholdPrediction(app, res, anoms, labels, dataY, threshold, windowSize, scoresPointwise, scoresEventwise, scoresPointAdjusted, scoresComposite)
            if iscell(dataY)
                dataY = cell2mat(dataY);
            end
            plot(app.DE_DynamicThresholdingDataUIAxes, dataY);
            
            threshY = [];
            threshX = [];
            for i = 1:length(threshold)
                threshY(i,:) = ones(1, windowSize) * threshold(i);
                threshX(i,:) = ((i - 1) * windowSize + 1):(windowSize * i);
            end

            time = 1:length(res);
            indices = getIndexes(anoms);
            trueIndices = getIndexes(labels);
            
            YLims = get(app.DE_DynamicThresholdingDataUIAxes, 'YLim');
            limit = [];
            trueLims = [];
            for i = 1:size(indices, 2)
                limit(1,i) = YLims(1);
                limit(2, i) = YLims(1);
                limit(3, i) = YLims(2);
                limit(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1,i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            
            if ~isempty(indices) && ~isempty(limit)
                patch(app.DE_DynamicThresholdingDataUIAxes, indices, limit, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
            end
            if ~isempty(trueIndices) && ~isempty(trueLims)
                patch(app.DE_DynamicThresholdingDataUIAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
            end

            try
                legend(app.DE_DynamicThresholdingDataUIAxes, 'data', "Detected Anomalies", "True Labels")
            catch ME
                legend(app.DE_DynamicThresholdingDataUIAxes, 'data', "Detected Anomalies")
            end
            
            plot(app.DE_DynamicThresholdingAnomalyScoresUIAxes, time, res)
            hold(app.DE_DynamicThresholdingAnomalyScoresUIAxes, "on")
            plot(app.DE_DynamicThresholdingAnomalyScoresUIAxes, threshX', threshY', 'Color', 'red')
            hold(app.DE_DynamicThresholdingAnomalyScoresUIAxes, "off")
            set(app.DE_DynamicThresholdingAnomalyScoresUIAxes, 'XLim', [0 length(res)])
            
            % Reorder scores
            scoresPointwise = [scoresPointwise(3); scoresPointwise(4); scoresPointwise(1); scoresPointwise(2)];
            scoresEventwise = [scoresEventwise(3); scoresEventwise(4); scoresEventwise(1); scoresEventwise(2)];
            scoresPointAdjusted = [scoresPointAdjusted(3); scoresPointAdjusted(4); scoresPointAdjusted(1); scoresPointAdjusted(2)];

            app.DE_DynamicThresholdingUITable.Data = table(["F1 Score"; "F0.5 Score"; "Precision"; "Recall"], ...
                scoresPointwise, scoresEventwise, scoresPointAdjusted, [scoresComposite; NaN; NaN]);
        end

        function plotStaticThresholdPrediction(app, anomalyScores, anoms, labels, dataY, threshold, scoresPointwise, scoresEventwise, scoresPointAdjusted, scoresComposite)
            if iscell(dataY)
                dataY = cell2mat(dataY);
            end
            plot(app.DE_StaticThresholdingDataUIAxes, dataY);

            time = 1:length(anomalyScores);
            indices = getIndexes(anoms);
            trueIndices = getIndexes(labels);

            plot(app.DE_StaticThresholdingAnomalyScoresUIAxes, time, anomalyScores)
            yline(app.DE_StaticThresholdingAnomalyScoresUIAxes, threshold, 'r')

            limit = [];
            YLims = get(app.DE_StaticThresholdingDataUIAxes, 'YLim');
            for i = 1:size(indices, 2)
                limit(1,i) = YLims(1);
                limit(2, i) = YLims(1);
                limit(3, i) = YLims(2);
                limit(4, i) = YLims(2);
            end
            for i = 1:size(trueIndices, 2)
                trueLims(1,i) = YLims(1);
                trueLims(2, i) = YLims(1);
                trueLims(3, i) = YLims(2);
                trueLims(4, i) = YLims(2);
            end
            
            
            if ~isempty(indices) && ~isempty(limit)
                patch(app.DE_StaticThresholdingDataUIAxes, indices, limit, 'red', 'EdgeColor', 'none', 'FaceAlpha', 0.4)
            end
            if ~isempty(trueIndices) && ~isempty(trueLims)
                patch(app.DE_StaticThresholdingDataUIAxes, trueIndices, trueLims, 'green', 'EdgeColor', 'none', 'FaceAlpha', 0.3)
            end

            try
                legend(app.DE_StaticThresholdingDataUIAxes, 'data',"Detected Anomalies", "True Labels")
            catch ME
                legend(app.DE_StaticThresholdingDataUIAxes, 'data', "Detected anomalies")
            end
            
            set(app.DE_StaticThresholdingAnomalyScoresUIAxes, 'XLim', [0 length(anomalyScores)])
            set(app.DE_StaticThresholdingDataUIAxes, 'XLim', [0 length(anomalyScores)])
            
            % Reorder scores
            scoresPointwise = [scoresPointwise(3); scoresPointwise(4); scoresPointwise(1); scoresPointwise(2)];
            scoresEventwise = [scoresEventwise(3); scoresEventwise(4); scoresEventwise(1); scoresEventwise(2)];
            scoresPointAdjusted = [scoresPointAdjusted(3); scoresPointAdjusted(4); scoresPointAdjusted(1); scoresPointAdjusted(2)];

            app.DE_StaticThresholdingUITable.Data = table(["F1 Score"; "F0.5 Score"; "Precision"; "Recall"], ...
                scoresPointwise, scoresEventwise, scoresPointAdjusted, [scoresComposite; NaN; NaN]);
        end
        
        function updateFullScoreTable(app, scoresPointwise, scoresEventwise, scoresPointAdjusted, scoresComposite, modelName, type)
            oldScores = app.fullScores.(modelName);
            switch type                
                case 'static'    
                    app.fullScores.(modelName) = [scoresComposite(1); ...
                        scoresPointwise(3); ...
                        scoresEventwise(3); ...
                        scoresPointAdjusted(3); ...
                        scoresComposite(2); ...
                        scoresPointwise(4); ...
                        scoresEventwise(4); ...
                        scoresPointAdjusted(4); ...
                        scoresPointwise(1); ...
                        scoresEventwise(1); ...
                        scoresPointAdjusted(1); ...
                        scoresPointwise(2); ...
                        scoresEventwise(2); ...
                        scoresPointAdjusted(2)
                        oldScores(15:end)];
                case 'dynamic'
                    app.fullScores.(modelName) = [oldScores(1:14); ...
                        scoresComposite(1); ...
                        scoresPointwise(3); ...
                        scoresEventwise(3); ...
                        scoresPointAdjusted(3); ...
                        scoresComposite(2); ...
                        scoresPointwise(4); ...
                        scoresEventwise(4); ...
                        scoresPointAdjusted(4); ...
                        scoresPointwise(1); ...
                        scoresEventwise(1); ...
                        scoresPointAdjusted(1); ...
                        scoresPointwise(2); ...
                        scoresEventwise(2); ...
                        scoresPointAdjusted(2)];
            end
        end
        
        function updateDetection(app, options, anomalyScores, dataY, labels, staticThreshold, dynamicOptions, modelName, type, plotEnabled)
            if strcmp(type, 'All') || strcmp(type, 'Static')
                [anomsStatic, staticThreshold] = calcStaticThresholdPrediction(anomalyScores, labels, staticThreshold, options.calcThresholdLast, options.model);

                [scoresPointwiseStatic, scoresEventwiseStatic, ...
                    scoresPointAdjustedStatic, scoresCompositeStatic] = calcScores(anomsStatic, labels);

                if plotEnabled
                    app.plotStaticThresholdPrediction(anomalyScores, anomsStatic, labels, dataY, staticThreshold, ...
                        scoresPointwiseStatic, scoresEventwiseStatic, scoresPointAdjustedStatic, scoresCompositeStatic)

                end
                
                app.updateFullScoreTable(scoresPointwiseStatic, scoresEventwiseStatic, scoresPointAdjustedStatic, scoresCompositeStatic, modelName, 'static');
            end
            
            if strcmp(type, 'All') || strcmp(type, 'Dynamic')
                padding = dynamicOptions.padding;
                windowSize = dynamicOptions.windowSize;           
                z_range = 1:dynamicOptions.z_range;
                min_percent = 1;
    
                [anomsDynamic, threshold] = calcDynamicThresholdPrediction(anomalyScores, labels, padding, ...
                    windowSize, min_percent, z_range);
                [scoresPointwiseDynamic, scoresEventwiseDynamic, ...
                    scoresPointAdjustedDynamic, scoresCompositeDynamic] = calcScores(anomsDynamic, labels);

                if plotEnabled
                    app.plotDynamicThresholdPrediction(anomalyScores, anomsDynamic, labels, dataY, threshold, windowSize, ...
                        scoresPointwiseDynamic, scoresEventwiseDynamic, scoresPointAdjustedDynamic, scoresCompositeDynamic)
                end

                app.updateFullScoreTable(scoresPointwiseDynamic, scoresEventwiseDynamic, scoresPointAdjustedDynamic, scoresCompositeDynamic, modelName, 'dynamic');
            end
        end
        
        function runDetectionFor(app, models)
            % Main detection function
            % Loops over all models and runs the detection accordingly
            % Input: models - struct with fields: type, name
            %                 type: One of DNN, CML Novelty, CML Outlier, S
            %                 name: id for the model which corresponds to a
            %                       fieldname in the trainedModels struct
            %                       Example: Hybrid_CNN_LSTM_1
            if isempty(models)
                uialert(app.UIFigure, "No Models (selected).", ...
                                'Detection Error');
                return;
            end

            selectedItem = app.DE_FaultyDataSelectionDropDown.Value;
            [~, idxData] = ismember(selectedItem, app.DE_FaultyDataSelectionDropDown.Items);

            for i = 1:numel(models)
                % Run detection according to type of model (DNN, CML,...)
                switch models(i).type
                    case 'DNN'
                        if i == numel(models)
                            % Run detection, save scores and plot results for last model
                            app.runDetections_DNN(models(i).name, idxData, true);
                        else
                            % Otherwise just run detection and save scores
                            app.runDetections_DNN(models(i).name, idxData, false);
                        end                                              
                    case 'CML'
                        if i == numel(models)
                            % Run detection, save scores and plot results for last model
                            app.runDetections_CML(models(i).name, idxData, true);
                        else
                            % Otherwise just run detection and save scores
                            app.runDetections_CML(models(i).name, idxData, false);
                        end
                    case 'S'
                        if i == numel(models)
                            % Run detection, save scores and plot results for last model
                            app.runDetections_S(models(i).name, idxData, true);
                        else
                            % Otherwise just run detection and save scores
                            app.runDetections_S(models(i).name, idxData, false);
                        end   
                end     
            end
        end

        function finishDetection(app, options, anomalyScores, YTest, labels, modelName, type, staticThreshold, plotEnabled)
            if iscell(YTest)
                maxWindowSize = floor(size(YTest{1, 1}, 1) / 2);
            else
                maxWindowSize = floor(length(YTest) / 2);
            end
            dynamicOptions.padding = 3;
            dynamicOptions.windowSize = maxWindowSize;
            dynamicOptions.z_range = app.DE_ZRangeSlider.Value;  

            if plotEnabled
                % If plot is enabled, run detection, save scores and plot 
                % results in uifigures
                app.DE_AnomalyPaddingSlider.Limits = [1 50];
                app.DE_AnomalyPaddingSlider.Value = dynamicOptions.padding;
                app.DE_WindowSizeSlider.Limits = [1 maxWindowSize];
                app.DE_WindowSizeSlider.Value = dynamicOptions.windowSize;                
                
                if ~options.calcThresholdLast    
                    if isfield(staticThreshold, app.currentThreshold)
                        selectedThreshold = staticThreshold.(app.currentThreshold);
                    else
                        thrFields = fieldnames(staticThreshold);
                        selectedThreshold = staticThreshold.(thrFields{1});
                    end

                    app.updateDetection(options, anomalyScores, YTest, labels, ...
                        selectedThreshold, dynamicOptions, modelName, 'All', true);
                else
                    app.updateDetection(options, anomalyScores, YTest, labels, ...
                        app.currentThreshold, dynamicOptions, modelName, 'All', true);
                end
    
                app.currentAnomalyScores = anomalyScores;
                app.currentYTest = YTest;
                app.currentLabels = labels;
                app.currentModelName = modelName;
                app.currentModelType = type;
                app.currentOptions = options;
    
                currentModelLabel = app.trainedModels.(modelName).options.label;
                app.DE_DetectionShownForLabel.Text = sprintf('Detection shown for: %s', currentModelLabel);
            else
                % If not, just run detection and save
                % scores
                if ~options.calcThresholdLast
                    if isfield(staticThreshold, app.currentThreshold)
                        selectedThreshold = staticThreshold.(app.currentThreshold);
                    else
                        thrFields = fieldnames(staticThreshold);
                        selectedThreshold = staticThreshold.(thrFields{1});
                    end
                    app.updateDetection(anomalyScores, YTest, labels, ...
                        selectedThreshold, dynamicOptions, modelName, 'All', false)
                else
                    app.updateDetection(options, anomalyScores, YTest, labels, ...
                        app.currentThreshold, dynamicOptions, modelName, 'All', false);
                end
            end
        end
        
        function showTrainedModels(app)
            if isempty(app.trainedModels)
                % If no models are loaded or trained
                app.DE_TrainedModelsListBox.Items = {};
                app.DE_TrainedModelsListBox.ItemsData = [];
                return;
            end            

            app.DE_TrainedModelsListBox.Items = {};
            app.DE_TrainedModelsListBox.ItemsData = [];
            
            if ~isempty(app.trainedModels)
                fields = fieldnames(app.trainedModels);
                for i = 1:numel(fields)
                    label = app.trainedModels.(fields{i}).options.label;

                    modelInfo.type = app.trainedModels.(fields{i}).options.type;
                    modelInfo.name = fields{i};

                    app.DE_TrainedModelsListBox.Items = [app.DE_TrainedModelsListBox.Items ...
                        label];
                    app.DE_TrainedModelsListBox.ItemsData = [app.DE_TrainedModelsListBox.ItemsData ...
                        modelInfo];
                end
            end
        end
        
        function sortTrainedModels(app)
            sortBy = app.DE_SortByDropDown.Value;
            scoreNames = [
                "Composite F1 Score (Static)"; ...
                "Point-wise F1 Score (Static)"; ...
                "Event-wise F1 Score (Static)"; ...
                "Point-adjusted F1 Score (Static)"; ...
                "Composite F0.5 Score (Static)"; ...
                "Point-wise F0.5 Score (Static)"; ...
                "Event-wise F0.5 Score (Static)"; ...
                "Point-adjusted F0.5 Score (Static)"; ...
                "Point-wise Precision (Static)"; ...
                "Event-wise Precision (Static)"; ...
                "Point-adjusted Precision (Static)"; ...
                "Point-wise Recall (Static)"; ...
                "Event-wise Recall (Static)"; ...
                "Point-adjusted Recall (Static)"; ...
                "Composite F1 Score (Dynamic)"; ...
                "Point-wise F1 Score (Dynamic)"; ...
                "Event-wise F1 Score (Dynamic)"; ...
                "Point-adjusted F1 Score (Dynamic)"; ...
                "Composite F0.5 Score (Dynamic)"; ...
                "Point-wise F0.5 Score (Dynamic)"; ...
                "Event-wise F0.5 Score (Dynamic)"; ...
                "Point-adjusted F0.5 Score (Dynamic)"; ...
                "Point-wise Precision (Dynamic)"; ...
                "Event-wise Precision (Dynamic)"; ...
                "Point-adjusted Precision (Dynamic)"; ...
                "Point-wise Recall (Dynamic)"; ...
                "Event-wise Recall (Dynamic)"; ...
                "Point-adjusted Recall (Dynamic)"];
            [~, rowIdx] = ismember(sortBy, scoreNames);

            numOfNets = length(app.DE_TrainedModelsListBox.ItemsData);
            values = zeros(1, numOfNets);
            for i = 1:numOfNets
                fieldName = app.DE_TrainedModelsListBox.ItemsData(i).name;
                modelScores = app.fullScores.(fieldName);

                values(i) = modelScores(rowIdx, 1);
                if isnan(values(i))
                    values(i) = 0;
                end
            end

            [~, idxs] = sort(values, 'descend');

            sortedItems = app.DE_TrainedModelsListBox.Items(idxs);
            sortedItemsData = app.DE_TrainedModelsListBox.ItemsData(idxs);
            
            for i = 1:length(sortedItems)
                sortedItems{i} = sprintf('%d. %s', i, sortedItems{i});
            end

            app.DE_TrainedModelsListBox.Items = sortedItems;
            app.DE_TrainedModelsListBox.ItemsData = sortedItemsData;
        end
     
        function showScoresInTable(app)
            app.DE_FullDetectionUITable.Data = {};
            if ~isempty(app.fullScores)
                app.DE_FullDetectionUITable.Data = app.fullScores;
                app.DE_FullDetectionUITable.ColumnName = app.fullScores.Properties.VariableNames;

                
                if size(app.fullScores, 2) > 1
                    removeStyle(app.DE_FullDetectionUITable);
                    
                    app.highlightTableMaxima(app.DE_FullDetectionUITable, 'all');
                end
            end
        end

%% DNN detection
        
        function runDetections_DNN(app, modelName, idxData, plotEnabled)
            trainedModel = app.trainedModels.(modelName);
            options = trainedModel.options;
            Mdl = trainedModel.Mdl;
            testingData = app.dataTest(idxData, 1);
            testingLabels = app.labelsTest(idxData, 1);

            [XTest, YTest, labels] = prepareDataTest_DNN(options, testingData, testingLabels);
            
            [anomalyScores, YTest, labels] = detectWithDNN(options, Mdl, XTest, YTest, labels);
            
            staticThreshold = trainedModel.staticThreshold;
            
            app.finishDetection(options, anomalyScores, YTest, labels, modelName, 'DNN', staticThreshold, plotEnabled);            
        end

%% CML detection
        
        function runDetections_CML(app, modelName, idxData, plotEnabled)
            trainedModel = app.trainedModels.(modelName);
            options = trainedModel.options;
            testingData = app.dataTest(idxData, 1);
            testingLabels = app.labelsTest(idxData, 1);          
            
            Mdl = trainedModel.Mdl;    
            [XTest, YTest, labels] = prepareDataTest_CML(options, testingData, testingLabels);

            [anomalyScores, YTest, labels] = detectWithCML(options, Mdl, XTest, YTest, labels);

            % Load static threshold
            staticThreshold = trainedModel.staticThreshold;

            app.finishDetection(options, anomalyScores, YTest, labels, modelName, 'CML', staticThreshold, plotEnabled);
        end
        
%% S Detection
        
        function runDetections_S(app, modelName, idxData, plotEnabled)
            trainedModel = app.trainedModels.(modelName);
            options = trainedModel.options;
            testingData = app.dataTest(idxData, 1);
            testingLabels = app.labelsTest(idxData, 1);          
            
            Mdl = trainedModel.Mdl;    
            [XTest, YTest, labels] = prepareDataTest_S(options, testingData, testingLabels);

            [anomalyScores, YTest, labels] = detectWithS(options, Mdl, XTest, YTest, labels);

            % Load static threshold
            staticThreshold = trainedModel.staticThreshold;

            app.finishDetection(options, anomalyScores, YTest, labels, modelName, 'S', staticThreshold, plotEnabled);
        end

    end

    methods (Access = public)
        
        function addModel_DNN(app, options)
            [label, id] = app.getLabelForModel(options.model, app.MT_DNN_ModelsListBox);
            options.id = id;
            options.label = label;
            modelInfo.options = options;

            app.MT_DNN_ModelsListBox.Items = [app.MT_DNN_ModelsListBox.Items label];
            app.MT_DNN_ModelsListBox.ItemsData = [app.MT_DNN_ModelsListBox.ItemsData modelInfo];

            app.toggleTrainButtons_DNN(true);
        end

        function addModel_CML(app, options)
            [label, id] = app.getLabelForModel(options.model, app.MT_CML_ModelsListBox);
            options.id = id;
            options.label = label;
            modelInfo.options = options;

            app.MT_CML_ModelsListBox.Items = [app.MT_CML_ModelsListBox.Items label];
            app.MT_CML_ModelsListBox.ItemsData = [app.MT_CML_ModelsListBox.ItemsData modelInfo];

            app.toggleTrainButtons_CML(true);
        end
    
        function addModel_S(app, options)
            [label, id] = app.getLabelForModel(options.model, app.MT_S_ModelsListBox);
            options.id = id;
            options.label = label;
            modelInfo.options = options;

            app.MT_S_ModelsListBox.Items = [app.MT_S_ModelsListBox.Items label];
            app.MT_S_ModelsListBox.ItemsData = [app.MT_S_ModelsListBox.ItemsData modelInfo];

            app.toggleTrainButtons_S(true);
        end
    
        function runOptimization(app, options, optVars, iterations, score)
            if app.info_optimizationRunning
                return;
            end
            app.optimizeModel(options, optVars, iterations, score);
        end
        
        function runOptimizationFor(app, iterations, cmpScore, selection)
            switch selection
                case 'DNN_selection'
                    models = app.MT_DNN_ModelsListBox.Value;
                case 'CML_selection'
                    models = app.MT_CML_ModelsListBox.Value;
                case 'S_selection'
                    models = app.MT_S_ModelsListBox.Value;
                case 'all'
                    % Load all models as added on the Training tab
                    models_DNN = app.MT_DNN_ModelsListBox.ItemsData;
                    models_CML = app.MT_CML_ModelsListBox.ItemsData;
                    models_S = app.MT_S_ModelsListBox.ItemsData;
                    
                    models = [];
                    if ~isempty(models_DNN)
                        models = [models models_DNN];
                    end
                    if ~isempty(models_CML)
                        models = [models models_CML];
                    end
                    if ~isempty(models_S)
                        models = [models models_S];
                    end         
            end

            if isempty(models)
                uialert(app.UIFigure, "No models found for optimization.", 'Model Error', Icon='error');
                return;
            end

            app.runOptimizations(models, iterations, cmpScore)
        end

        function runOptimizations(app, models, iterations, cmpScore)
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Running Auto Optimization',...
                Indeterminate='on');
            drawnow;

            if ~app.P_SplitTestSetCheckBox.Value
                ratioValTest = 1;
            else
                ratioValTest = app.P_RatioValTestEditField.Value;
            end
            
            configOptFileName = 'config_optimization.json';

            switch cmpScore
                case 'Point-wise F1 Score'
                    threshold = "bestFscorePointwise";
                case 'Event-wise F1 Score'
                    threshold = "bestFscoreEventwise";
                case 'Point-adjusted F1 Score'
                    threshold = "bestFscorePointAdjusted";
                case 'Composite F1 Score'
                    threshold = "bestFscoreComposite";
            end

            bestOptions = autoOptimization(models, app.dataTrain, app.labelsTrain, ...
                                                app.dataValTest, app.labelsValTest, app.dataTest, ...
                                                app.labelsTest, ratioValTest, configOptFileName, ...
                                                cmpScore, threshold, iterations);
            
            % Add optimized models to lists and change their labels and ids
            DNN_labels = [];
            CML_labels = [];
            S_labels = [];
            
            if isfield(bestOptions, "DNN_Models")
                for i = 1:length(bestOptions.DNN_Models)
                    bestOptions.DNN_Models(i).options.label = bestOptions.DNN_Models(i).options.id + " (optimized)";
                    bestOptions.DNN_Models(i).options.id = bestOptions.DNN_Models(i).options.id + "_optimized";
                    DNN_labels = [DNN_labels, bestOptions.DNN_Models(i).options.label];
                end
            end
            if isfield(bestOptions, "CML_Models")
                for i = 1:length(bestOptions.CML_Models)
                    bestOptions.CML_Models(i).options.label = bestOptions.CML_Models(i).options.id + " (optimized)";
                    bestOptions.CML_Models(i).options.id = bestOptions.CML_Models(i).options.id + "_optimized";
                    CML_labels = [CML_labels, bestOptions.CML_Models(i).options.label];
                end
            end
            if isfield(bestOptions, "S_Models")
                for i = 1:length(bestOptions.S_Models)
                    bestOptions.S_Models(i).options.label = bestOptions.S_Models(i).options.id + " (optimized)";
                    bestOptions.S_Models(i).options.id = bestOptions.S_Models(i).options.id + "_optimized";
                    S_labels = [S_labels, bestOptions.S_Models(i).options.label];
                end
            end
            
            app.MT_DNN_ModelsListBox.Items = [app.MT_DNN_ModelsListBox.Items, bestOptions.DNN_labels];
            app.MT_CML_ModelsListBox.Items = [app.MT_DNN_ModelsListBox.Items, bestOptions.CML_labels];
            app.MT_S_ModelsListBox.Items = [app.MT_DNN_ModelsListBox.Items, bestOptions.S_labels];

            app.MT_DNN_ModelsListBox.ItemsData = [app.MT_DNN_ModelsListBox.ItemsData, bestOptions.DNN_Models];
            app.MT_CML_ModelsListBox.ItemsData = [app.MT_DNN_ModelsListBox.ItemsData, bestOptions.CML_Models];
            app.MT_S_ModelsListBox.ItemsData = [app.MT_DNN_ModelsListBox.ItemsData, bestOptions.S_Models];


            close(d);
            uialert(app.UIFigure, 'Successfully finished optimization. Best config saved to config_optimized.json inside the dataset folder. You can find the optimized models in the Detection tab.', 'Evaluation Successful', Icon='success');
            app.endTask(oldPointer);
        end
    
        function updateThresholds(app, newThresholds)
            app.thresholds = newThresholds;
            
            [r, idx] = ismember("dynamic", app.thresholds);
            if r
                items = app.thresholds;
                items(idx) = [];
            else 
                items = app.thresholds;
            end
            app.DE_ThresholdDropDown.Items = items;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.MT_PreviewUIAxes, 'off') % Turn off axes for model preview
            app.fullScores.Properties.VariableNames = "Metric";

            [r, idx] = ismember("dynamic", app.thresholds);
            if r
                items = app.thresholds;
                items(idx) = [];
            else 
                items = app.thresholds;
            end
            app.DE_ThresholdDropDown.Items = items;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.ModelSelectionApp_DNN)
                app.ModelSelectionApp_DNN.delete()
            end
            if ~isempty(app.ModelSelectionApp_CML)
                app.ModelSelectionApp_CML.delete()
            end
            if ~isempty(app.ModelSelectionApp_S)
                app.ModelSelectionApp_S.delete()
            end
            if ~isempty(app.OptimizeParametersApp)
                app.OptimizeParametersApp.delete()
            end
            if ~isempty(gcp('nocreate'))
                delete(gcp('nocreate'));
            end
            app.delete()
        end

        % Selection change function: MainPanel
        function MainPanelSelectionChanged(app, event)
%             % This is a workaround for disabling tabs since there is no
%             % enable parameter for individual tabs
%             selectedTab = app.MainPanel.SelectedTab;
%             if ~app.loadedTrainingData
%                 app.MainPanel.SelectedTab = event.OldValue;
%             end
        end

        % Menu selected function: ThresholdsMenu
        function ThresholdsMenuSelected(app, event)
            app.ThresholdSelectionApp = ThresholdSelection(app, app.thresholds);
        end

        % Callback function
        function D_MainButtonGroupSelectionChanged(app, event)
            selectedButton = app.D_MainButtonGroup.SelectedObject;
            switch selectedButton
                case app.D_AvailableDatasetsButton
                    app.D_AvailableDatasetsDropDown.Enable = 'on';
                    app.D_AvailableDatasetsDropDownLabel.Enable = 'on';
                    app.D_BrowseCustomDatasetButton.Enable = 'off';
                    app.D_ImportCustomDatasetLabel.Enable = 'off';
                    app.D_ImportCustomDatasetEditField.Enable = 'off';
                    app.D_ImportCustomDatasetEditField.Editable = 'off';
                case app.D_CustomDatasetButton
                    app.D_AvailableDatasetsDropDown.Enable = 'off';
                    app.D_AvailableDatasetsDropDownLabel.Enable = 'off';
                    app.D_BrowseCustomDatasetButton.Enable = 'on';
                    app.D_ImportCustomDatasetLabel.Enable = 'on';
                    app.D_ImportCustomDatasetEditField.Enable = 'on';
                    app.D_ImportCustomDatasetEditField.Editable = 'on';
            end
            cla(app.D_RawTrainingDatasetPreviewUIAxes);
            cla(app.D_RawTestingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTrainingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTestingDatasetPreviewUIAxes);
            app.togglePreprocessingTab(false);
            app.toggleTrainButtons_DNN(false);
            app.toggleDetectionEvaluationTab(false);
        end

        % Callback function
        function D_AvailableDatasetsDropDownValueChanged(app, event)
            cla(app.D_RawTrainingDatasetPreviewUIAxes);
            cla(app.D_RawTestingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTrainingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTestingDatasetPreviewUIAxes);
            app.showDataPreparationFor('None');
            app.togglePreprocessingTab(false);
            app.toggleTrainButtons_DNN(false);
            app.toggleDetectionEvaluationTab(false);
        end

        % Button pushed function: D_BrowseCustomDatasetButton
        function D_BrowseCustomDatasetButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            path = uigetdir(pwd);
            delete(f);
            if ~isequal(path, 0)
                app.D_ImportCustomDatasetEditField.Value = path;
            end
        end

        % Button pushed function: D_LoadDataButton
        function D_LoadDataButtonPushed(app, event)
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Loading Dataset',...
                Indeterminate='on');
            drawnow;

            cla(app.D_RawTrainingDatasetPreviewUIAxes);
            cla(app.D_RawTestingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTrainingDatasetPreviewUIAxes);
            cla(app.P_PreprocessedTestingDatasetPreviewUIAxes);
            
            app.dataTrainRaw = [];
            app.timestampsTrain = [];
            app.labelsTrain = [];
            app.filesTrain = [];
            app.dataTestRaw = [];
            app.timestampsTestFull = [];
            app.timestampsTest = [];
            app.filesTestFull = [];
            app.labelsTest = [];
            app.labelsTestFull = [];

            [app.dataTrainRaw, ...
                app.timestampsTrain, ...
                app.labelsTrain, ...
                app.filesTrain, ...
                app.dataTestRaw, ...
                app.timestampsTest, ...
                app.labelsTest, ...
                app.filesTestFull] = loadCustomDataset(app.D_ImportCustomDatasetEditField.Value, 'test');

            if isempty(app.dataTestRaw) || isempty(app.labelsTest) || isempty(app.timestampsTest)
                close(d);
                uialert(app.UIFigure, ['No training or testing data found! Please make make sure the selected folder contains' ...
                    'the following directories: train, test. The folders should include .csv files with fields:' ...
                    'timestamp, value1, value2, ..., is_anomaly'], ...
                            'Data Error');
                app.endTask(oldPointer);
                return;
            end


            app.labelsTestFull = app.labelsTest;

            app.showPreprocParameters();


            if ~isempty(app.dataTrainRaw)
                app.plotLoadedData('train-raw', 1, 1);
                app.createPreviewLegend('train-raw', 1);

                numChannels = size(app.dataTrainRaw{1, 1}, 2);
                if numChannels > 1
                    app.info_dataIsMultivariate = true;
                else
                    app.info_dataIsMultivariate = false;
                end

                app.D_SelectedFileTrainDropDown.Items = app.filesTrain;
                app.P_SelectedFileTrainDropDown.Items = app.filesTrain;
            end

            if ~isempty(app.dataTestRaw)
                app.plotLoadedData('test-raw', 1, 1);
                app.createPreviewLegend('test-raw', 1);

                numChannels = size(app.dataTestRaw{1, 1}, 2);
                if numChannels > 1
                    app.info_dataIsMultivariate = true;
                else
                    app.info_dataIsMultivariate = false;
                end

                app.D_SelectedFileTestDropDown.Items = app.filesTestFull;
            end
            
            % Initialize channel selection dropdown menus for
            % preview
            items = arrayfun(@(a)num2str(a), 1:numChannels, 'uni', 0);
            app.D_SelectedChannelTrainDropDown.Items = items;
            app.D_SelectedChannelTestDropDown.Items = items;
            app.P_SelectedChannelTrainDropDown.Items = items;
            app.P_SelectedChannelTestDropDown.Items = items;
            app.P_SelectedChannelValTestDropDown.Items = items;

            app.runPreprocessing(true)

            app.info_dataLoaded = true;

            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: D_SelectedFileTrainDropDown
        function D_SelectedFileTrainDropDownValueChanged(app, event)
            app.updatePreviewData('train-raw');
        end

        % Value changed function: D_SelectedChannelTrainDropDown
        function D_SelectedChannelTrainDropDownValueChanged(app, event)
            app.updatePreviewData('train-raw');
        end

        % Value changed function: D_SelectedFileTestDropDown
        function D_SelectedFileTestDropDownValueChanged(app, event)
            app.updatePreviewData('test-raw');
        end

        % Value changed function: D_SelectedChannelTestDropDown
        function D_SelectedChannelTestDropDownValueChanged(app, event)
            app.updatePreviewData('test-raw');
        end

        % Button pushed function: D_NextButton
        function D_NextButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.PreprocessingTab;
        end

        % Selection changed function: P_MethodButtonGroup
        function P_MethodButtonGroupSelectionChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Preprocessing Dataset',...
                Indeterminate='on');
            drawnow;
            
            app.runPreprocessing(true);
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: P_SplitTestSetCheckBox
        function P_SplitTestSetCheckBoxValueChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end
            
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Preprocessing Dataset',...
                Indeterminate='on');
            drawnow;
            
            if app.P_SplitTestSetCheckBox.Value
                app.P_RatioValTestLabel.Enable = 'on';
                app.P_RatioValTestEditField.Enable = 'on';
            else
                app.P_RatioValTestLabel.Enable = 'off';
                app.P_RatioValTestEditField.Enable = 'off';
            end

            app.runPreprocessing(true);
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: P_RatioValTestEditField
        function P_RatioValTestEditFieldValueChanged(app, event)
            if ~app.info_dataLoaded
                return;
            end
            
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Preprocessing Dataset',...
                Indeterminate='on');
            drawnow;
            
            app.runPreprocessing(true);
            
            close(d);
            app.endTask(oldPointer);            
        end

        % Value changed function: P_SelectedFileTrainDropDown
        function P_SelectedFileTrainDropDownValueChanged(app, event)
            app.updatePreviewData('train');
        end

        % Value changed function: P_SelectedChannelTrainDropDown
        function P_SelectedChannelTrainDropDownValueChanged(app, event)
            app.updatePreviewData('train');
        end

        % Value changed function: P_SelectedFileTestDropDown
        function P_SelectedFileTestDropDownValueChanged(app, event)
            app.updatePreviewData('test');
        end

        % Value changed function: P_SelectedChannelTestDropDown
        function P_SelectedChannelTestDropDownValueChanged(app, event)
            app.updatePreviewData('test');
        end

        % Value changed function: P_SelectedFileValTestDropDown
        function P_SelectedFileValTestDropDownValueChanged(app, event)
            app.updatePreviewData('val-test');
        end

        % Value changed function: P_SelectedChannelValTestDropDown
        function P_SelectedChannelValTestDropDownValueChanged(app, event)
            app.updatePreviewData('val-test');
        end

        % Button pushed function: P_PreviousButton
        function P_PreviousButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.DataTab;
        end

        % Button pushed function: P_NextButton
        function P_NextButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.ModelTrainingTab;
        end

        % Callback function
        function MT_NetworkSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.MT_NetworkSelectionButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.MT_NewNetworksButton
                    app.MT_PretrainedNetworkLabel.Enable = 'off';
                    app.MT_PretrainedNetworkTextArea.Enable = 'off';
                    app.MT_PretrainedNetworkBrowseButton.Enable = 'off';
                    app.MT_PretrainedNetworkLoadButton.Enable = 'off';
                    app.MT_NetworksLabel.Enable = 'on';
                    app.MT_DNN_ModelsListBox.Enable = 'on';
                    app.MT_DNN_AddModelsButton.Enable = 'on';
                    app.MT_DNN_ClearModelsButton.Enable = 'on';
                case app.MT_PretrainedNetworkButton
                    app.MT_PretrainedNetworkLabel.Enable = 'on';
                    app.MT_PretrainedNetworkTextArea.Enable = 'on';
                    app.MT_PretrainedNetworkBrowseButton.Enable = 'on';
                    app.MT_PretrainedNetworkLoadButton.Enable = 'on';
                    app.MT_NetworksLabel.Enable = 'off';
                    app.MT_DNN_ModelsListBox.Enable = 'off';
                    app.MT_DNN_AddModelsButton.Enable = 'off';
                    app.MT_DNN_ClearModelsButton.Enable = 'off';
            end
        end

        % Button pushed function: MT_DNN_AddModelsButton
        function MT_DNN_AddModelsButtonPushed(app, event)
            app.ModelSelectionApp_DNN = ModelSelection_DNN(app);
        end

        % Button pushed function: MT_DNN_AddAllModelsButton
        function MT_DNN_AddAllModelsButtonPushed(app, event)
            app.MT_DNN_ModelsListBox.Items = {};
            app.MT_DNN_ModelsListBox.ItemsData = [];
            
            if ~isempty(app.trainedModels)
                fields = fieldnames(app.trainedModels);
                for i = 1:length(fields)
                    if strcmp(app.trainedModels.(fields{i}).options.type, 'DNN')
                        app.trainedModels = rmfield(app.trainedModels, fields{i});
                    end
                end
            end

            app.showTrainedModels();
            app.showScoresInTable();            

            % Load all models with their configuration
            fid = fopen('config_all.json');
            raw = fread(fid, inf);
            str = char(raw');
            fclose(fid);
            models_All = jsondecode(str);
            
            models_DNN = models_All.DNN_Models;
            
            numModels = length(models_DNN);
            modelNames = strings(1, numModels);
            for i = 1:numModels
                modelNames(i) = models_DNN(i).options.label;
            end

            app.MT_DNN_ModelsListBox.Items = modelNames;
            app.MT_DNN_ModelsListBox.ItemsData = models_DNN;

            app.toggleTrainButtons_DNN(true);
        end

        % Button pushed function: MT_DNN_ClearModelsButton
        function MT_DNN_ClearModelsButtonPushed(app, event)
            app.MT_DNN_ModelsListBox.Items = {};
            app.MT_DNN_ModelsListBox.ItemsData = [];

            app.resetDetection();
            
            if ~isempty(app.trainedModels)
                fields = fieldnames(app.trainedModels);
                app.trainedModels = rmfield(app.trainedModels, fields);
            end

            app.showTrainedModels();
            app.showScoresInTable();

            app.toggleTrainButtons_DNN(false);
        end

        % Menu selected function: MT_DNN_ShowModelParametersMenu
        function MT_DNN_ShowModelParametersMenuSelected(app, event)
            selection = app.MT_DNN_ModelsListBox.Value;
            if isempty(selection)
                uialert(app.UIFigure, "No model selected for viewing! Select model first.", ...
                        'Model Viewer Error');
                return;
            end
            if length(selection) > 1
                uialert(app.UIFigure, "Can't disply multiple models at once! Please select just a single model.", ...
                        'Model Viewer Error');
                return;
            end

            oldPointer = app.beginTask();

            selectedModel = selection.options;
            
            app.showModelPreview(selectedModel);

            app.endTask(oldPointer);
        end

        % Menu selected function: MT_DNN_DeleteMenu
        function MT_DNN_DeleteMenuSelected(app, event)
            selectedItems = app.MT_DNN_ModelsListBox.Value;

            for i = 1:length(selectedItems)
                [~, idx] = ismember(selectedItems(i).options.label, app.MT_DNN_ModelsListBox.Items);
                app.MT_DNN_ModelsListBox.Items(idx) = [];
                app.MT_DNN_ModelsListBox.ItemsData(idx) = [];
                if isstruct(app.trainedModels)
                    if isfield(app.trainedModels, selectedItems(i).options.id)
                        app.trainedModels = rmfield(app.trainedModels, selectedItems(i).options.id);
                    end
                    if ~isempty(app.currentModelName)
                        if app.currentModelName == selectedItems(i).options.id
                            app.resetDetection();
                        end
                    end
                end
            end

            app.showTrainedModels();
            app.showScoresInTable();

            if isempty(app.MT_DNN_ModelsListBox.Items)
                app.toggleTrainButtons_DNN(false)
            end

            app.updateModelLabels(app.MT_DNN_ModelsListBox);
        end

        % Button pushed function: MT_DNN_TrainSelectedModelsButton
        function MT_DNN_TrainSelectedModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end
            
            models = app.MT_DNN_ModelsListBox.Value;

            if isempty(app.MT_DNN_ModelsListBox.Value)
                uialert(app.UIFigure, 'No models selected for training!', 'Training Error');
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;

            app.trainDNNs(models);
            
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_LoadFromFileButton
        function MT_LoadFromFileButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            [file, path] = uigetfile('*.json', ...
               'Select a configuration file', pwd);
            delete(f);

            if ~isequal(file, 0) && ~isequal(path, 0)
                filePath = fullfile(path, file);
                fid = fopen(filePath);
                raw = fread(fid, inf);
                str = char(raw');
                fclose(fid);                
                models = jsondecode(str);
            
                app.MT_DNN_ModelsListBox.Items = {};
                app.MT_DNN_ModelsListBox.ItemsData = [];
                app.MT_CML_ModelsListBox.Items = {};
                app.MT_CML_ModelsListBox.ItemsData = [];
                app.MT_S_ModelsListBox.Items = {};
                app.MT_S_ModelsListBox.ItemsData = [];
                
                for i = 1:length(models.DNN_Models)
                    app.addModel_DNN(models.DNN_Models(i).options);
                end
                for i = 1:length(models.CML_Models)
                    app.addModel_CML(models.CML_Models(i).options);
                end
                for i = 1:length(models.S_Models)
                    app.addModel_S(models.S_Models(i).options);
                end
            end            
        end

        % Button pushed function: MT_DNN_TrainAllModelsButton
        function MT_DNN_TrainAllModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end
            
            models = app.MT_DNN_ModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.UIFigure, 'No models found for training!', 'Training Error');
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;
            
            app.trainDNNs(models);
            
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_DNN_OptimizeHyperparametersButton
        function MT_DNN_OptimizeHyperparametersButtonPushed(app, event)
            models = app.MT_DNN_ModelsListBox.Value;
            if isempty(models)
                uialert(app.UIFigure, 'No models selected for optimization', 'Optimization Error', Icon='error');
                return;
            end
            numOfModels = length(models);
            if numOfModels > 1
                app.OptimizeAllApp = OptimizeAll(app, 'DNN_selection');
                return;
            end
            if ~isempty(app.OptimizeParametersApp)
                app.OptimizeParametersApp.delete();
            end
            app.OptimizeParametersApp = OptimizeParameters(app, models(1).options);
        end

        % Value changed function: MT_DNN_ModelsListBox
        function MT_DNN_ModelsListBoxValueChanged(app, event)
            value = app.MT_DNN_ModelsListBox.Value;
            if length(value) == 1
                app.toggleOptimizeButton_DNN(true);
            else
                app.toggleOptimizeButton_DNN(false);
            end
        end

        % Button pushed function: MT_CML_AddModelButton
        function MT_CML_AddModelButtonPushed(app, event)
            app.ModelSelectionApp_CML = ModelSelection_CML(app);
        end

        % Button pushed function: MT_CML_AddAllModelsButton
        function MT_CML_AddAllModelsButtonPushed(app, event)
            app.MT_CML_ModelsListBox.Items = {};
            app.MT_CML_ModelsListBox.ItemsData = [];
            
            if ~isempty(app.trainedModels)
                fields = fieldnames(app.trainedModels);
                for i = 1:length(fields)
                    if strcmp(app.trainedModels.(fields{i}).options.type, 'CML')
                        app.trainedModels = rmfield(app.trainedModels, fields{i});
                    end
                end
            end

            app.showTrainedModels();
            app.showScoresInTable();            

            % Load all models with their configuration
            fid = fopen('config_all.json');
            raw = fread(fid, inf);
            str = char(raw');
            fclose(fid);
            models_All = jsondecode(str);
            
            models_CML = models_All.CML_Models;
            
            numModels = length(models_CML);
            modelNames = strings(1, numModels);
            for i = 1:numModels
                modelNames(i) = models_CML(i).options.label;
            end

            app.MT_CML_ModelsListBox.Items = modelNames;
            app.MT_CML_ModelsListBox.ItemsData = models_CML;

            app.toggleTrainButtons_CML(true);
        end

        % Button pushed function: MT_CML_ClearModelsButton
        function MT_CML_ClearModelsButtonPushed(app, event)
            app.MT_CML_ModelsListBox.Items = {};
            app.MT_CML_ModelsListBox.ItemsData = [];

            app.resetDetection();
            
            if ~isempty(app.trainedModels)
                fields = fieldnames(app.trainedModels);
                app.trainedModels = rmfield(app.trainedModels, fields);
            end

            app.showTrainedModels();
            app.showScoresInTable();

            app.toggleTrainButtons_CML(false);
        end

        % Menu selected function: MT_CML_ShowModelParametersMenu
        function MT_CML_ShowModelParametersMenuSelected(app, event)
            selection = app.MT_CML_ModelsListBox.Value;
            if isempty(selection)
                uialert(app.UIFigure, "No model selected for viewing! Select model first.", ...
                        'Model Viewer Error');
                return;
            end
            if length(selection) > 1
                uialert(app.UIFigure, "Can't disply multiple models at once! Please select just a single model.", ...
                        'Model Viewer Error');
                return;
            end

            oldPointer = app.beginTask();

            selectedModel = selection.options;
            
            app.showModelPreview(selectedModel);

            app.endTask(oldPointer);
        end

        % Menu selected function: MT_CML_DeleteMenu
        function MT_CML_DeleteMenuSelected(app, event)
            selectedItems = app.MT_CML_ModelsListBox.Value;

            for i = 1:length(selectedItems)
                [~, idx] = ismember(selectedItems(i).options.label, app.MT_CML_ModelsListBox.Items);
                app.MT_CML_ModelsListBox.Items(idx) = [];
                app.MT_CML_ModelsListBox.ItemsData(idx) = [];
                if isstruct(app.trainedModels)
                    if isfield(app.trainedModels, selectedItems(i).options.id)
                        app.trainedModels = rmfield(app.trainedModels, selectedItems(i).options.id);
                    end
                    if ~isempty(app.currentModelName)
                        if app.currentModelName == selectedItems(i).options.id
                            app.resetDetection();
                        end
                    end
                end
            end

            app.showTrainedModels();
            app.showScoresInTable();

            if isempty(app.MT_CML_ModelsListBox.Items)
                app.toggleTrainButtons_CML(false)
            end

            app.updateModelLabels(app.MT_CML_ModelsListBox);
        end

        % Button pushed function: MT_CML_TrainSelectedModelsButton
        function MT_CML_TrainSelectedModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end
            
            models = app.MT_CML_ModelsListBox.Value;

            if isempty(models)
                uialert(app.UIFigure, 'No models selected for training!', 'Training Error');
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;
            
            app.trainCMLs(models);
            
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_CML_TrainAllModelsButton
        function MT_CML_TrainAllModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end

            models = app.MT_CML_ModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.UIFigure, 'No models found for training!', 'Training Error', Icon='error');
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;
            
            app.trainCMLs(models);
        
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_CML_OptimizeHyperparametersButton
        function MT_CML_OptimizeHyperparametersButtonPushed(app, event)
            models = app.MT_CML_ModelsListBox.Value;
            if isempty(models)
                uialert(app.UIFigure, 'No models selected for optimization', 'Optimization Error', Icon='error');
                return;
            end
            numOfModels = length(models);
            if numOfModels > 1
                app.OptimizeAllApp = OptimizeAll(app, 'CML_selection');
                return;
            end
            if ~isempty(app.OptimizeParametersApp)
                app.OptimizeParametersApp.delete();
            end
            app.OptimizeParametersApp = OptimizeParameters(app, models(1).options);
        end

        % Value changed function: MT_CML_ModelsListBox
        function MT_CML_ModelsListBoxValueChanged(app, event)
            value = app.MT_CML_ModelsListBox.Value;
            if length(value) == 1
                app.toggleOptimizeButton_CML(true);
            else
                app.toggleOptimizeButton_CML(false);
            end
        end

        % Button pushed function: MT_S_AddModelButton
        function MT_S_AddModelButtonPushed(app, event)
            app.ModelSelectionApp_S = ModelSelection_S(app);
        end

        % Button pushed function: MT_S_AddAllModelsButton
        function MT_S_AddAllModelsButtonPushed(app, event)
            app.MT_S_ModelsListBox.Items = {};
            app.MT_S_ModelsListBox.ItemsData = [];
            
            if ~isempty(app.trainedModels)
                fields = fieldnames(app.trainedModels);
                for i = 1:length(fields)
                    if strcmp(app.trainedModels.(fields{i}).options.type, 'S')
                        app.trainedModels = rmfield(app.trainedModels, fields{i});
                    end
                end
            end

            app.showTrainedModels();
            app.showScoresInTable();            

            % Load all models with their configuration
            fid = fopen('config_all.json');
            raw = fread(fid, inf);
            str = char(raw');
            fclose(fid);
            models_All = jsondecode(str);
            
            models_S = models_All.S_Models;
            
            numModels = length(models_S);
            modelNames = strings(1, numModels);
            for i = 1:numModels
                modelNames(i) = models_S(i).options.label;
            end

            app.MT_S_ModelsListBox.Items = modelNames;
            app.MT_S_ModelsListBox.ItemsData = models_S;

            app.toggleTrainButtons_S(true);
        end

        % Button pushed function: MT_S_ClearModelsButton
        function MT_S_ClearModelsButtonPushed(app, event)
            app.MT_S_ModelsListBox.Items = {};
            app.MT_S_ModelsListBox.ItemsData = [];

            app.resetDetection();
            
            if ~isempty(app.trainedModels)
                fields = fieldnames(app.trainedModels);
                app.trainedModels = rmfield(app.trainedModels, fields);
            end

            app.showTrainedModels();
            app.showScoresInTable();

            app.toggleTrainButtons_S(false);
        end

        % Menu selected function: MT_S_ShowModelParametersMenu
        function MT_S_ShowModelParametersMenuSelected(app, event)
            selection = app.MT_S_ModelsListBox.Value;
            if isempty(selection)
                uialert(app.UIFigure, "No model selected for viewing! Select model first.", ...
                        'Model Viewer Error');
                return;
            end
            if length(selection) > 1
                uialert(app.UIFigure, "Can't disply multiple models at once! Please select just a single model.", ...
                        'Model Viewer Error');
                return;
            end

            oldPointer = app.beginTask();

            selectedModel = selection.options;
            
            app.showModelPreview(selectedModel);

            app.endTask(oldPointer);
        end

        % Menu selected function: MT_S_DeleteMenu
        function MT_S_DeleteMenuSelected(app, event)
            selectedItems = app.MT_S_ModelsListBox.Value;

            for i = 1:length(selectedItems)
                [~, idx] = ismember(selectedItems(i).options.label, app.MT_S_ModelsListBox.Items);
                app.MT_S_ModelsListBox.Items(idx) = [];
                app.MT_S_ModelsListBox.ItemsData(idx) = [];
                if isstruct(app.trainedModels)
                    if isfield(app.trainedModels, selectedItems(i).options.id)
                        app.trainedModels = rmfield(app.trainedModels, selectedItems(i).options.id);
                    end
                    if ~isempty(app.currentModelName)
                        if app.currentModelName == selectedItems(i).options.id
                            app.resetDetection();
                        end
                    end
                end
            end

            app.showTrainedModels();
            app.showScoresInTable();

            if isempty(app.MT_S_ModelsListBox.Items)
                app.toggleTrainButtons_S(false)
            end

            app.updateModelLabels(app.MT_S_ModelsListBox);
        end

        % Button pushed function: MT_S_TrainSelectedModelsButton
        function MT_S_TrainSelectedModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end

            models = app.MT_S_ModelsListBox.Value;

            if isempty(models)
                uialert(app.UIFigure, 'No models selected for training!', 'Training Error');
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;
            
            app.trainSs(models);
            
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_S_TrainAllModelsButton
        function MT_S_TrainAllModelsButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded for training!', 'Training Error', Icon='error');
                return;
            end

            models = app.MT_S_ModelsListBox.ItemsData;

            if isempty(models)
                uialert(app.UIFigure, 'No models found for training!', 'Training Error');
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;
            
            app.trainSs(models);
            
            close(d);
            uialert(app.UIFigure, 'Successfully trained models.', 'Training finished', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_S_OptimizeHyperparametersButton
        function MT_S_OptimizeHyperparametersButtonPushed(app, event)
            models = app.MT_S_ModelsListBox.Value;
            if isempty(models)
                uialert(app.UIFigure, 'No models selected for optimization', 'Optimization Error', Icon='error');
                return;
            end
            numOfModels = length(models);
            if numOfModels > 1
                app.OptimizeAllApp = OptimizeAll(app, 'S_selection');
                return;
            end
            if ~isempty(app.OptimizeParametersApp)
                app.OptimizeParametersApp.delete();
            end
            app.OptimizeParametersApp = OptimizeParameters(app, models(1).options);
        end

        % Value changed function: MT_S_ModelsListBox
        function MT_S_ModelsListBoxValueChanged(app, event)
            value = app.MT_S_ModelsListBox.Value;
            if length(value) == 1
                app.toggleOptimizeButton_S(true);
            else
                app.toggleOptimizeButton_S(false);
            end
        end

        % Button pushed function: MT_TrainAllButton
        function MT_TrainAllButtonPushed(app, event)
            DNN_models = app.MT_DNN_ModelsListBox.ItemsData;
            CML_models = app.MT_CML_ModelsListBox.ItemsData;
            S_models = app.MT_S_ModelsListBox.ItemsData;

            if isempty(DNN_models) && isempty(CML_models) && isempty(S_models)
                uialert(app.UIFigure, 'No models found for training!', 'Training Error');
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Training Models',...
                Indeterminate='on');
            drawnow;
            
            if ~isempty(DNN_models)
                app.trainDNNs(DNN_models);
            end
            if ~isempty(CML_models)
                app.trainCMLs(CML_models);
            end
            if ~isempty(S_models)
                app.trainSs(S_models);
            end
            
            close(d);
            app.endTask(oldPointer);
        end

        % Button pushed function: MT_OptimizeAllButton
        function MT_OptimizeAllButtonPushed(app, event)
            DNN_models = app.MT_DNN_ModelsListBox.ItemsData;
            CML_models = app.MT_CML_ModelsListBox.ItemsData;
            S_models = app.MT_S_ModelsListBox.ItemsData;

            if isempty(DNN_models) && isempty(CML_models) && isempty(S_models)
                uialert(app.UIFigure, 'No models found for training!', 'Training Error');
                return;
            end

            app.OptimizeAllApp = OptimizeAll(app, 'all');
        end

        % Button pushed function: MT_PreviousButton
        function MT_PreviousButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.PreprocessingTab;
        end

        % Button pushed function: MT_NextButton
        function MT_NextButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.DetectionEvaluationTab;
        end

        % Button pushed function: DE_RunDetectionForSelectedButton
        function DE_RunDetectionForSelectedButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded!', ...
                            'Detection Error', Icon='error');
                return;
            end
            
            models = app.DE_TrainedModelsListBox.Value;
            if isempty(models)
                uialert(app.UIFigure, 'No models selected!', ...
                            'Detection Error', Icon='error');
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Running Detection',...
                Indeterminate='on');
            drawnow;

            app.runDetectionFor(models);
                    
            app.info_detectionRun = true;
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            
            close(d);
            uialert(app.UIFigure, 'Detection finished successfully!', ...
                            'Detection Success', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: DE_RunDetectionForAllButton
        function DE_RunDetectionForAllButtonPushed(app, event)
            if ~app.info_dataLoaded
                uialert(app.UIFigure, 'No data loaded!', ...
                            'Detection Error', Icon='error');
                return;
            end
            
            models = app.DE_TrainedModelsListBox.ItemsData;
            if isempty(models)
                uialert(app.UIFigure, 'No models found!', ...
                            'Detection Error', Icon='error');
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Running Detection',...
                Indeterminate='on');
            drawnow;

            app.runDetectionFor(models);
            
            app.info_detectionRun = true;
            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            
            close(d);
            uialert(app.UIFigure, 'Detection finished successfully!', ...
                            'Detection Success', Icon='success');
            app.endTask(oldPointer);
        end

        % Value changed function: DE_AnomalyPaddingSlider
        function DE_AnomalyPaddingSliderValueChanged(app, event)
            if ~app.info_detectionRun
                return;
            end

            if ~app.info_dataLoaded
                return;
            end
            
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Updating Detection',...
                Indeterminate='on');
            drawnow;

            dynamicOptions.padding = floor(app.DE_AnomalyPaddingSlider.Value);
            dynamicOptions.windowSize = floor(app.DE_WindowSizeSlider.Value);
            dynamicOptions.z_range = app.DE_ZRangeSlider.Value;

            if ~isempty(app.currentAnomalyScores)
                app.updateDetection(app.currentOptions, app.currentAnomalyScores, app.currentYTest, app.currentLabels, ...
                    0, dynamicOptions, app.currentModelName, 'Dynamic', true)
            end

            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: DE_WindowSizeSlider
        function DE_WindowSizeSliderValueChanged(app, event)
            if ~app.info_detectionRun
                return;
            end
            
            if ~app.info_dataLoaded
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Updating Detection',...
                Indeterminate='on');
            drawnow;
            
            dynamicOptions.padding = floor(app.DE_AnomalyPaddingSlider.Value);
            dynamicOptions.windowSize = floor(app.DE_WindowSizeSlider.Value);
            dynamicOptions.z_range = app.DE_ZRangeSlider.Value;

            if ~isempty(app.currentAnomalyScores)
                app.updateDetection(app.currentOptions, app.currentAnomalyScores, app.currentYTest, app.currentLabels, ...
                    0, dynamicOptions, app.currentModelName, 'Dynamic', true)
            end

            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: DE_ZRangeSlider
        function DE_ZRangeSliderValueChanged(app, event)
            if ~app.info_detectionRun
                return;
            end

            if ~app.info_dataLoaded
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Updating Detection',...
                Indeterminate='on');
            drawnow;

            dynamicOptions.padding = floor(app.DE_AnomalyPaddingSlider.Value);
            dynamicOptions.windowSize = floor(app.DE_WindowSizeSlider.Value);
            dynamicOptions.z_range = app.DE_ZRangeSlider.Value;

            if ~isempty(app.currentAnomalyScores)
                app.updateDetection(app.currentOptions, app.currentAnomalyScores, app.currentYTest, app.currentLabels, ...
                    0, dynamicOptions, app.currentModelName, 'Dynamic', true)
            end

            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: DE_ThresholdDropDown
        function DE_ThresholdDropDownValueChanged(app, event)
            if ~app.info_detectionRun
                return;
            end

            if ~app.info_dataLoaded
                return;
            end

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Updating Detection',...
                Indeterminate='on');
            drawnow;

            dynamicOptions.padding = app.DE_AnomalyPaddingSlider.Value;
            dynamicOptions.windowSize = app.DE_WindowSizeSlider.Value;
            dynamicOptions.z_range = app.DE_ZRangeSlider.Value;

            selectedItem = app.DE_ThresholdDropDown.Value;

            if ~app.currentOptions.calcThresholdLast
                staticThreshold = app.trainedModels.(app.currentModelName).staticThreshold.(selectedItem);
                if ~isempty(app.currentAnomalyScores)
                    app.updateDetection(app.currentOptions, app.currentAnomalyScores, app.currentYTest, app.currentLabels, ...
                        staticThreshold, dynamicOptions, app.currentModelName, 'Static', true);
                end
            else
                if ~isempty(app.currentAnomalyScores)
                    app.updateDetection(app.currentOptions, app.currentAnomalyScores, app.currentYTest, app.currentLabels, ...
                        selectedItem, dynamicOptions, app.currentModelName, 'Static', true);
                end
            end

            app.showTrainedModels();
            app.sortTrainedModels();
            app.showScoresInTable();
            
            close(d);
            app.endTask(oldPointer);
        end

        % Value changed function: DE_SortByDropDown
        function DE_SortByDropDownValueChanged(app, event)
            oldPointer = app.beginTask();
            
            app.showTrainedModels();
            app.sortTrainedModels();

            app.endTask(oldPointer);
        end

        % Button pushed function: DE_ExportTableButton
        function DE_ExportTableButtonPushed(app, event)
            folder = fullfile(pwd, 'Testing_Results');
            if ~exist(folder, 'dir')
                mkdir(folder)
            end
            datasetPath = strsplit(app.D_ImportCustomDatasetEditField.Value, filesep);
            datasetName = datasetPath{end};
            folder = fullfile(folder, datasetName);
            if ~exist(folder, 'dir')
                mkdir(folder)
            end

            fileName_Results = fullfile(folder, sprintf('Results_%s.csv', datestr(now,'mm-dd-yyyy_HH-MM')));
            table = app.DE_FullDetectionUITable.Data;
            table.Properties.VariableNames = app.DE_FullDetectionUITable.ColumnName;
            
            variableNames = table.Properties.VariableNames;
            if length(variableNames) < 2
                return;
            end
            fileName_ModelInfo = fullfile(folder, sprintf('ModelInfo_%s.txt', datestr(now,'mm-dd-yyyy_HH-MM')));
            for i = 2:length(variableNames)
                modelInfo.(variableNames{i}) = app.trainedModels.(variableNames{i}).options;
            end
            
            fileID = fopen(fileName_ModelInfo, 'w');
            fprintf(fileID, jsonencode(modelInfo, PrettyPrint=true));    
            fclose(fileID);

            writetable(table, fileName_Results);
            uialert(app.UIFigure, sprintf('Saved table to %s', fileName_Results), 'Table Saved', Icon='success');
        end

        % Button pushed function: DE_SaveSelectedModelsButton
        function DE_SaveSelectedModelsButtonPushed(app, event)
            selectedModels = app.DE_TrainedModelsListBox.Value;
            if isempty(selectedModels)
                uialert(app.UIFigure, 'No models selected for export!', 'Export Error');
                return;
            end

            folder = fullfile(pwd, 'Exported_Models');
            if ~exist(folder, 'dir')
                mkdir(folder)
            end
            datasetPath = strsplit(app.D_ImportCustomDatasetEditField.Value, filesep);
            datasetName = datasetPath{end};
            folder = fullfile(folder, datasetName);
            if ~exist(folder, 'dir')
                mkdir(folder)
            end            
            folder = fullfile(folder, datestr(now,'mm-dd-yyyy_HH-MM'));
            if ~exist(folder, 'dir')
                mkdir(folder)
            end
                        
            for i = 1:length(selectedModels)
                modelFieldName = selectedModels(i).name;
                Mdl = app.trainedModels.(modelFieldName).Mdl;
                
                if ~isempty(Mdl)
                    fileName = fullfile(folder, sprintf('%s.mat', modelFieldName));
                    assignin('base', 'Mdl', Mdl);
                    save(fileName, 'Mdl');
                end
            end
            uialert(app.UIFigure, sprintf('Saved models to %s', folder), 'Models Saved', Icon='success');
        end

        % Button pushed function: DE_PreviousButton
        function DE_PreviousButtonPushed(app, event)
            app.MainPanel.SelectedTab = app.ModelTrainingTab;
        end

        % Button pushed function: DE_NextButton
        function DE_NextButtonPushed(app, event)
            
        end

        % Callback function
        function AR_AutoBrowseButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            path = uigetdir(pwd);
            delete(f);
            if ~isequal(path, 0)
                app.AR_AutoDatasetEditField.Value = path;
            end
        end

        % Callback function
        function AR_RunOptimizationButtonPushed(app, event)
            datasetPath = app.AR_AutoDatasetEditField.Value;
            if ~exist(datasetPath, 'dir')
                uialert(app.UIFigure, "Dataset path doesn't exist!", 'Dataset Error', Icon='error');
                return;
            end

            if ~app.AR_AutoUseModelsCheckBox.Value
                % Load all models with their configuration from file
                configFileName = 'config_for_testing.json';

                fid = fopen(configFileName);
                raw = fread(fid, inf);
                str = char(raw');
                fclose(fid);
                models_All = jsondecode(str);
                
                models = [];
                if isfield(models_All, 'DNN_Models')
                    models = [models; models_All.DNN_Models];
                end
                
                if isfield(models_All, 'CML_Models')
                    models = [models; models_All.CML_Models];
                end
                
                if isfield(models_All, 'S_Models')
                    models = [models; models_All.S_Models];
                end
            else
                % Load all models as added on the Training tab
                models_DNN = app.MT_DNN_ModelsListBox.ItemsData;
                models_CML = app.MT_CML_ModelsListBox.ItemsData;
                models_S = app.MT_S_ModelsListBox.ItemsData;
                
                models = [];
                if ~isempty(models_DNN)
                    models = [models models_DNN];
                end
                if ~isempty(models_CML)
                    models = [models models_CML];
                end
                if ~isempty(models_S)
                    models = [models models_S];
                end
            end

            if isempty(models)
                uialert(app.UIFigure, "No models found for optimization.", 'Model Error', Icon='error');
                return;
            end
            
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Running Auto Optimization',...
                Indeterminate='on');
            drawnow;

            preprocMethod = app.AR_AutoPreprocessingMethodDropDown.Value;
            ratioValTest = app.AR_RatioValTestOptEditField.Value;          
            configOptFileName = 'config_optimization.json';
            iterations = app.AR_IterationEditField.Value;
            cmpScore = app.AR_AutoOptimizeForDropDown.Value;

            switch cmpScore
                case 'Point-wise F1 Score'
                    threshold = "bestFscorePointwise";
                case 'Event-wise F1 Score'
                    threshold = "bestFscoreEventwise";
                case 'Point-adjusted F1 Score'
                    threshold = "bestFscorePointAdjusted";
                case 'Composite F1 Score'
                    threshold = "bestFscoreComposite";
            end

            [~, finalTable, optimizedModels] = autoOptimization(datasetPath, models, ...
                                                configOptFileName, preprocMethod, ...
                                                ratioValTest, cmpScore, threshold, ...
                                                iterations);
            
            fields = fieldnames(optimizedModels);
            for i = 1:length(fields)
                app.fullScores.(fields{i}) = [NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                              NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; ...
                                              NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN; NaN];
                app.trainedModels.(fields{i}) = optimizedModels.(fields{i});
            end

            app.AR_AutoResultsUITable.Data = finalTable;
            app.AR_AutoResultsUITable.ColumnName = finalTable.Properties.VariableNames;

            removeStyle(app.AR_AutoResultsUITable);
            app.highlightTableMaxima(app.AR_AutoResultsUITable, 'rowwise');
            
            app.showTrainedModels();

            close(d);
            uialert(app.UIFigure, 'Successfully finished optimization. Best config saved to config_optimized.json inside the dataset folder. You can find the optimized models in the Detection tab.', 'Evaluation Successful', Icon='success');
            app.endTask(oldPointer);
        end

        % Callback function
        function AR_AutoExportButtonPushed(app, event)
            datasetPath = app.AR_AutoDatasetEditField.Value;
            if ~exist(datasetPath, 'dir')
                uialert(app.UIFigure, "Dataset path doesn't exist!", 'Export Error', Icon='error');
                return;
            end

            fileName_Results = fullfile(datasetPath, sprintf('Results_%s.csv', datestr(now,'mm-dd-yyyy_HH-MM')));
            table = app.AR_AutoResultsUITable.Data;
            table.Properties.VariableNames = app.AR_AutoResultsUITable.ColumnName;

            writetable(table, fileName_Results);
            uialert(app.UIFigure, sprintf('Saved table to %s', fileName_Results), 'Table Saved', Icon='success');
        end

        % Button pushed function: AR_SweepBrowseButton
        function AR_SweepBrowseButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            path = uigetdir(pwd);
            delete(f);
            if ~isequal(path, 0)
                app.AR_SweepDatasetEditField.Value = path;
            end
        end

        % Button pushed function: AR_RunEvaluationButton
        function AR_RunEvaluationButtonPushed(app, event)
            datasetPath = app.AR_SweepDatasetEditField.Value;
            if ~exist(datasetPath, 'dir')
                uialert(app.UIFigure, "Dataset path doesn't exist!", 'Dataset Error', Icon='error');
                return;
            end
            
            if ~app.AR_SweepUseModelsCheckBox.Value
                % Load all models with their configuration
                configFileName = "config_for_testing.json";

                fid = fopen(configFileName);
                raw = fread(fid, inf);
                str = char(raw');
                fclose(fid);
                models_All = jsondecode(str);
                
                models = [];
                if isfield(models_All, 'DNN_Models')
                    models.models_DNN = models_All.DNN_Models;
                else
                    models.models_DNN = [];
                end
                
                if isfield(models_All, 'CML_Models')
                    models.models_CML = models_All.CML_Models;
                else
                    models.models_CML = [];
                end
                
                if isfield(models_All, 'S_Models')
                    models.models_S = models_All.S_Models;
                else
                    models.models_S = [];
                end
            else
                % Load all models as added on the Training tab
                models.models_DNN = app.MT_DNN_ModelsListBox.ItemsData;
                models.models_CML = app.MT_CML_ModelsListBox.ItemsData;
                models.models_S = app.MT_S_ModelsListBox.ItemsData;
            end

            if isempty(models.models_DNN) && isempty(models.models_CML) && isempty(models.models_S)
                uialert(app.UIFigure, "No models found for evaluation.", 'Model Error', Icon='error');
                return;
            end 
 
            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Evaluating Dataset',...
                Indeterminate='on');
            drawnow;

            useFraction = app.AR_UseFractionEditField.Value;
            ratioValTest = app.AR_RatioValTestEditField.Value;
            preprocMethod = app.AR_PreprocessingMethodDropDown.Value;
            
            finalTable = evaluateAllForDataset(datasetPath, models, ...
                                                useFraction, preprocMethod, ...
                                                ratioValTest, app.thresholds);

            app.AR_SweepResultsUITable.Data = finalTable;
            app.AR_SweepResultsUITable.ColumnName = finalTable.Properties.VariableNames;

            removeStyle(app.AR_SweepResultsUITable);
            app.highlightTableMaxima(app.AR_SweepResultsUITable, 'rowwise');
            
            close(d);
            uialert(app.UIFigure, 'Successfully finished evaluating all models on selected dataset. Results saved to Dataset_Sweep_Results folder in the current Matlab folder', 'Dataset Sweep Successful', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: AR_SweepExportButton
        function AR_SweepExportButtonPushed(app, event)
            datasetPath = app.AR_SweepDatasetEditField.Value;
            if ~exist(datasetPath, 'dir')
                uialert(app.UIFigure, "Dataset path doesn't exist!", 'Export Error', Icon='error');
                return;
            end

            fileName_Results = fullfile(datasetPath, sprintf('Results_%s.csv', datestr(now,'mm-dd-yyyy_HH-MM')));
            table = app.AR_SweepResultsUITable.Data;
            table.Properties.VariableNames = app.AR_SweepResultsUITable.ColumnName;

            writetable(table, fileName_Results);
            uialert(app.UIFigure, sprintf('Saved table to %s', fileName_Results), 'Table Saved', Icon='success');
        end

        % Button pushed function: DS_BrowseDatasetButton
        function DS_BrowseDatasetButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            path = uigetdir(pwd);
            delete(f);
            if ~isequal(path, 0)
                app.DS_DatasetEditField.Value = path;
            end
        end

        % Callback function
        function DS_BrowseConfigButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); % Dummy figure for keeping app in foreground after uigetfile
            [file, path] = uigetfile({'.json'}, 'Select config file', pwd);
            delete(f);
            if ~isempty(file) && ~isempty(path)
                app.DS_ConfigEditField.Value = fullfile(path, file);
            end
        end

        % Button pushed function: DS_AutoLabelDatasetButton
        function DS_AutoLabelDatasetButtonPushed(app, event)
            datasetPath = app.DS_DatasetEditField.Value;
            if ~exist(datasetPath, 'dir')
                uialert(app.UIFigure, "Dataset path doesn't exist!", 'Dataset Error', Icon='error');
                return;
            end

            if ~app.DS_UseModelsCheckBox.Value
                % Load all models with their configuration
                configFileName = fullfile(datasetPath, 'config_optimized.json');

                fid = fopen(configFileName);
                raw = fread(fid, inf);
                str = char(raw');
                fclose(fid);
                models_All = jsondecode(str);
                
                models = [];
                if isfield(models_All, 'DNN_Models')
                    models.models_DNN = models_All.DNN_Models;
                else
                    models.models_DNN = [];
                end
                
                if isfield(models_All, 'CML_Models')
                    models.models_CML = models_All.CML_Models;
                else
                    models.models_CML = [];
                end
                
                if isfield(models_All, 'S_Models')
                    models.models_S = models_All.S_Models;
                else
                    models.models_S = [];
                end
            else
                % Load all models as added on the Training tab                
                models.models_DNN = app.MT_DNN_ModelsListBox.ItemsData;
                models.models_CML = app.MT_CML_ModelsListBox.ItemsData;
                models.models_S = app.MT_S_ModelsListBox.ItemsData;
            end

            if isempty(models.models_DNN) && isempty(models.models_CML) && isempty(models.models_S)
                uialert(app.UIFigure, "No models found.", 'Model Error', Icon='error');
                return;
            end 

            oldPointer = app.beginTask();
            d = uiprogressdlg(app.UIFigure, Title='Labeling Dataset',...
                Indeterminate='on');
            drawnow;

            preprocMethod = app.DS_PreprocessingMethodDropDown.Value;
            ratioTestVal = 1;
            cmpMetric = app.DS_ScoreDropDown.Value;
            
            prepareDatasetForDynamicSwitch(datasetPath);
            findBestModels(datasetPath, models, preprocMethod, ratioTestVal, cmpMetric);
            
            app.toggleLamp(app.DS_AutoLabelLamp, true);
            close(d);
            uialert(app.UIFigure, 'Successfully split dataset, trained all models and labeled best models for each testing file.', 'Labeling Successful', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: DS_TrainClassifierButton
        function DS_TrainClassifierButtonPushed(app, event)
            oldPointer = app.beginTask();

            datasetPath = app.DS_DatasetEditField.Value;
            if ~exist(datasetPath, 'dir')
                uialert(app.UIFigure, "Dataset path doesn't exist!", 'Dataset Error', Icon='error');
                app.endTask(oldPointer);
                return;
            end

            d = uiprogressdlg(app.UIFigure, Title='Training classifier',...
                Indeterminate='on');
            drawnow;

            app.ds_classifier = trainDynamicSwitchClassifier(datasetPath);
            
            app.toggleLamp(app.DS_TrainLamp, true);
            close(d);
            uialert(app.UIFigure, 'Successfully trained classifier.', 'Training Successful', Icon='success');
            app.endTask(oldPointer);
        end

        % Button pushed function: DS_RunEvaluationsButton
        function DS_RunEvaluationsButtonPushed(app, event)
            oldPointer = app.beginTask();

            datasetPath = app.DS_DatasetEditField.Value;
            if ~exist(datasetPath, 'dir')
                uialert(app.UIFigure, "Dataset path doesn't exist!", 'Dataset Error', Icon='error');
                app.endTask(oldPointer);
                return;
            end

            d = uiprogressdlg(app.UIFigure, Title='Evaluating Dynamic Switch',...
                Indeterminate='on');
            drawnow;
            
            cmpMetric = app.DS_ScoreDropDown.Value;

            switch cmpMetric
                case 'Compsite F1 Score'
                    threshold = "bestFscoreComposite";
                case 'Point-wise F1 Score'
                    threshold = "bestFscorePointwise";
                case 'Event-wise F1 Score'
                    threshold = "bestFscoreEventwise";
                case 'Point-adjusted F1 Score'
                    threshold = "bestFscorePointAdjusted";
            end

            finalTable = evaluateDynamicSwitch(app.ds_classifier, datasetPath, threshold);
            app.DS_ResultsUITable.Data = finalTable;
            app.DS_ResultsUITable.ColumnName = finalTable.Properties.VariableNames;

            removeStyle(app.DS_ResultsUITable);
            app.highlightTableMaxima(app.DS_ResultsUITable, 'rowwise');
            
            app.toggleLamp(app.DS_EvaluationLamp, true);
            close(d);
            uialert(app.UIFigure, 'Successfully finished evaluation.', 'Evaluation Successful', Icon='success');
            app.endTask(oldPointer);
        end

        % Value changed function: DS_DatasetEditField
        function DS_DatasetEditFieldValueChanged(app, event)
            app.toggleLamp(app.DS_AutoLabelLamp, false);
            app.toggleLamp(app.DS_TrainLamp, false);
            app.toggleLamp(app.DS_EvaluationLamp, false);
        end

        % Button pushed function: DS_ExportButton
        function DS_ExportButtonPushed(app, event)
            datasetPath = app.DS_DatasetEditField.Value;
            if ~exist(datasetPath, 'dir')
                uialert(app.UIFigure, "Dataset path doesn't exist!", 'Export Error', Icon='error');
                return;
            end

            fileName_Results = fullfile(datasetPath, sprintf('Results_%s.csv', datestr(now,'mm-dd-yyyy_HH-MM')));
            table = app.DS_ResultsUITable.Data;
            table.Properties.VariableNames = app.DS_ResultsUITable.ColumnName;

            writetable(table, fileName_Results);
            uialert(app.UIFigure, sprintf('Saved table to %s', fileName_Results), 'Table Saved', Icon='success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ThresholdsMenu = uimenu(app.SettingsMenu);
            app.ThresholdsMenu.MenuSelectedFcn = createCallbackFcn(app, @ThresholdsMenuSelected, true);
            app.ThresholdsMenu.Text = 'Thresholds';
            app.MainPanel = uitabgroup(app.UIFigure);
            app.MainPanel.SelectionChangedFcn = createCallbackFcn(app, @MainPanelSelectionChanged, true);
            app.DataTab = uitab(app.MainPanel);
            app.DataTab.Title = '1.1 Data';
            app.DataPanel = uipanel(app.DataTab);
            app.D_RawTrainingDatasetPreviewUIAxes = uiaxes(app.DataPanel);
            title(app.D_RawTrainingDatasetPreviewUIAxes, 'Preview: Training Dataset (raw)')
            xlabel(app.D_RawTrainingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.D_RawTrainingDatasetPreviewUIAxes, 'Value')
            zlabel(app.D_RawTrainingDatasetPreviewUIAxes, 'Z')
            app.D_RawTestingDatasetPreviewUIAxes = uiaxes(app.DataPanel);
            title(app.D_RawTestingDatasetPreviewUIAxes, 'Preview: Testing Dataset (raw)')
            xlabel(app.D_RawTestingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.D_RawTestingDatasetPreviewUIAxes, 'Value')
            zlabel(app.D_RawTestingDatasetPreviewUIAxes, 'Z')
            app.D_LoadDataButton = uibutton(app.DataPanel, 'push');
            app.D_LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @D_LoadDataButtonPushed, true);
            app.D_LoadDataButton.Tooltip = {'Load the selected dataset'};
            app.D_LoadDataButton.Text = 'Load Data';
            app.D_NextButton = uibutton(app.DataPanel, 'push');
            app.D_NextButton.ButtonPushedFcn = createCallbackFcn(app, @D_NextButtonPushed, true);
            app.D_NextButton.Text = 'Next';
            app.SelectedChannelLabel = uilabel(app.DataPanel);
            app.SelectedChannelLabel.Text = 'Selected Channel:';
            app.D_SelectedChannelTrainDropDown = uidropdown(app.DataPanel);
            app.D_SelectedChannelTrainDropDown.Items = {};
            app.D_SelectedChannelTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedChannelTrainDropDownValueChanged, true);
            app.D_SelectedChannelTrainDropDown.Value = {};
            app.SelectedFileLabel = uilabel(app.DataPanel);
            app.SelectedFileLabel.Text = 'Selected File:';
            app.D_SelectedFileTrainDropDown = uidropdown(app.DataPanel);
            app.D_SelectedFileTrainDropDown.Items = {};
            app.D_SelectedFileTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedFileTrainDropDownValueChanged, true);
            app.D_SelectedFileTrainDropDown.Value = {};
            app.SelectedChannelLabel_2 = uilabel(app.DataPanel);
            app.SelectedChannelLabel_2.Text = 'Selected Channel:';
            app.D_SelectedChannelTestDropDown = uidropdown(app.DataPanel);
            app.D_SelectedChannelTestDropDown.Items = {};
            app.D_SelectedChannelTestDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedChannelTestDropDownValueChanged, true);
            app.D_SelectedChannelTestDropDown.Value = {};
            app.SelectedFileLabel_2 = uilabel(app.DataPanel);
            app.SelectedFileLabel_2.Text = 'Selected File:';
            app.D_SelectedFileTestDropDown = uidropdown(app.DataPanel);
            app.D_SelectedFileTestDropDown.Items = {};
            app.D_SelectedFileTestDropDown.ValueChangedFcn = createCallbackFcn(app, @D_SelectedFileTestDropDownValueChanged, true);
            app.D_SelectedFileTestDropDown.Value = {};
            app.D_BrowseCustomDatasetButton = uibutton(app.DataPanel, 'push');
            app.D_BrowseCustomDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @D_BrowseCustomDatasetButtonPushed, true);
            app.D_BrowseCustomDatasetButton.Text = 'Browse...';
            app.D_ImportCustomDatasetLabel = uilabel(app.DataPanel);
            app.D_ImportCustomDatasetLabel.Text = 'Dataset Folder:';
            app.D_ImportCustomDatasetEditField = uieditfield(app.DataPanel, 'text');
            app.PreprocessingTab = uitab(app.MainPanel);
            app.PreprocessingTab.Title = '1.2 Preprocessing';
            app.PreprocessingPanel = uipanel(app.PreprocessingTab);
            app.P_PreprocessedTrainingDatasetPreviewUIAxes = uiaxes(app.PreprocessingPanel);
            title(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'Preview: Training Dataset (preprocessed)')
            xlabel(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'Value')
            zlabel(app.P_PreprocessedTrainingDatasetPreviewUIAxes, 'Z')
            app.P_PreprocessedTestingDatasetPreviewUIAxes = uiaxes(app.PreprocessingPanel);
            title(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'Preview: Testing Dataset (preprocessed)')
            xlabel(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'Value')
            zlabel(app.P_PreprocessedTestingDatasetPreviewUIAxes, 'Z')
            app.P_PreprocessedValTestDatasetPreviewUIAxes = uiaxes(app.PreprocessingPanel);
            title(app.P_PreprocessedValTestDatasetPreviewUIAxes, 'Preview: Testing Validation Dataset (preprocessed)')
            xlabel(app.P_PreprocessedValTestDatasetPreviewUIAxes, 'Timestamp')
            ylabel(app.P_PreprocessedValTestDatasetPreviewUIAxes, 'Value')
            zlabel(app.P_PreprocessedValTestDatasetPreviewUIAxes, 'Z')
            app.P_NextButton = uibutton(app.PreprocessingPanel, 'push');
            app.P_NextButton.ButtonPushedFcn = createCallbackFcn(app, @P_NextButtonPushed, true);
            app.P_NextButton.Text = 'Next';
            app.P_PreviousButton = uibutton(app.PreprocessingPanel, 'push');
            app.P_PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @P_PreviousButtonPushed, true);
            app.P_PreviousButton.Text = 'Previous';
            app.P_MethodButtonGroup = uibuttongroup(app.PreprocessingPanel);
            app.P_MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @P_MethodButtonGroupSelectionChanged, true);
            app.P_MethodButtonGroup.Title = 'Preprocessing Method';
            app.P_RawDataButton = uiradiobutton(app.P_MethodButtonGroup);
            app.P_RawDataButton.Text = 'Raw Data';
            app.P_RawDataButton.Value = true;
            app.P_StandardizeButton = uiradiobutton(app.P_MethodButtonGroup);
            app.P_StandardizeButton.Text = 'Standardize';
            app.P_RescaleButton = uiradiobutton(app.P_MethodButtonGroup);
            app.P_RescaleButton.Text = 'Rescale [0, 1]';
            app.P_MaximumLabel = uilabel(app.PreprocessingPanel);
            app.P_MaximumLabel.Text = 'Maximum: -';
            app.P_ParametersLabel = uilabel(app.PreprocessingPanel);
            app.P_ParametersLabel.Text = 'Preprocessing Parameters:';
            app.P_MinimumLabel = uilabel(app.PreprocessingPanel);
            app.P_MinimumLabel.Text = 'Minimum: -';
            app.P_MeanLabel = uilabel(app.PreprocessingPanel);
            app.P_MeanLabel.Text = 'Mean: -';
            app.P_StandardDeviationLabel = uilabel(app.PreprocessingPanel);
            app.P_StandardDeviationLabel.Text = 'Standard Deviation: -';
            app.P_RatioValTestEditField = uieditfield(app.PreprocessingPanel, 'numeric');
            app.P_RatioValTestEditField.Limits = [0.1 0.9];
            app.P_RatioValTestEditField.ValueChangedFcn = createCallbackFcn(app, @P_RatioValTestEditFieldValueChanged, true);
            app.P_RatioValTestEditField.Value = 0.5;
            app.P_SplitTestSetCheckBox = uicheckbox(app.PreprocessingPanel);
            app.P_SplitTestSetCheckBox.ValueChangedFcn = createCallbackFcn(app, @P_SplitTestSetCheckBoxValueChanged, true);
            app.P_SplitTestSetCheckBox.Text = 'Split Test Set';
            app.P_SplitTestSetCheckBox.Value = true;
            app.SelectedChannelLabel_3 = uilabel(app.PreprocessingPanel);
            app.SelectedChannelLabel_3.Text = 'Selected Channel:';
            app.P_SelectedChannelTrainDropDown = uidropdown(app.PreprocessingPanel);
            app.P_SelectedChannelTrainDropDown.Items = {};
            app.P_SelectedChannelTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @P_SelectedChannelTrainDropDownValueChanged, true);
            app.P_SelectedChannelTrainDropDown.Value = {};
            app.SelectedFileLabel_3 = uilabel(app.PreprocessingPanel);
            app.SelectedFileLabel_3.Text = 'Selected File:';
            app.P_SelectedFileTrainDropDown = uidropdown(app.PreprocessingPanel);
            app.P_SelectedFileTrainDropDown.Items = {};
            app.P_SelectedFileTrainDropDown.ValueChangedFcn = createCallbackFcn(app, @P_SelectedFileTrainDropDownValueChanged, true);
            app.P_SelectedFileTrainDropDown.Value = {};
            app.SelectedChannelLabel_4 = uilabel(app.PreprocessingPanel);
            app.SelectedChannelLabel_4.Text = 'Selected Channel:';
            app.P_SelectedChannelTestDropDown = uidropdown(app.PreprocessingPanel);
            app.P_SelectedChannelTestDropDown.Items = {};
            app.P_SelectedChannelTestDropDown.ValueChangedFcn = createCallbackFcn(app, @P_SelectedChannelTestDropDownValueChanged, true);
            app.P_SelectedChannelTestDropDown.Value = {};
            app.SelectedFileLabel_4 = uilabel(app.PreprocessingPanel);
            app.SelectedFileLabel_4.Text = 'Selected File:';
            app.P_SelectedFileTestDropDown = uidropdown(app.PreprocessingPanel);
            app.P_SelectedFileTestDropDown.Items = {};
            app.P_SelectedFileTestDropDown.ValueChangedFcn = createCallbackFcn(app, @P_SelectedFileTestDropDownValueChanged, true);
            app.P_SelectedFileTestDropDown.Value = {};
            app.SelectedChannelLabel_5 = uilabel(app.PreprocessingPanel);
            app.SelectedChannelLabel_5.Text = 'Selected Channel:';
            app.P_SelectedChannelValTestDropDown = uidropdown(app.PreprocessingPanel);
            app.P_SelectedChannelValTestDropDown.Items = {};
            app.P_SelectedChannelValTestDropDown.ValueChangedFcn = createCallbackFcn(app, @P_SelectedChannelValTestDropDownValueChanged, true);
            app.P_SelectedChannelValTestDropDown.Value = {};
            app.SelectedFileLabel_5 = uilabel(app.PreprocessingPanel);
            app.SelectedFileLabel_5.Text = 'Selected File:';
            app.P_SelectedFileValTestDropDown = uidropdown(app.PreprocessingPanel);
            app.P_SelectedFileValTestDropDown.Items = {};
            app.P_SelectedFileValTestDropDown.ValueChangedFcn = createCallbackFcn(app, @P_SelectedFileValTestDropDownValueChanged, true);
            app.P_SelectedFileValTestDropDown.Value = {};
            app.P_RatioValTestLabel = uilabel(app.PreprocessingPanel);
            app.P_RatioValTestLabel.Text = 'Ratio Val:Test:';
            app.ModelTrainingTab = uitab(app.MainPanel);
            app.ModelTrainingTab.Title = '1.3 Training';
            app.ModelTrainingPanel = uipanel(app.ModelTrainingTab);
            app.MT_NextButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_NextButton.ButtonPushedFcn = createCallbackFcn(app, @MT_NextButtonPushed, true);
            app.MT_NextButton.Text = 'Next';
            app.MT_PreviousButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @MT_PreviousButtonPushed, true);
            app.MT_PreviousButton.Text = 'Previous';
            app.MT_DeepNeuralNetworksPanel = uipanel(app.ModelTrainingPanel);
            app.MT_DeepNeuralNetworksPanel.Title = 'Deep Neural Networks';
            app.MT_DNN_ModelsListBox = uilistbox(app.MT_DeepNeuralNetworksPanel);
            app.MT_DNN_ModelsListBox.Items = {};
            app.MT_DNN_ModelsListBox.Multiselect = 'on';
            app.MT_DNN_ModelsListBox.ValueChangedFcn = createCallbackFcn(app, @MT_DNN_ModelsListBoxValueChanged, true);
            app.MT_DNN_ModelsListBox.Value = {};
            app.MT_DNN_AddModelsButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_AddModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_AddModelsButtonPushed, true);
            app.MT_DNN_AddModelsButton.Text = 'Add';
            app.MT_DNN_ClearModelsButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_ClearModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_ClearModelsButtonPushed, true);
            app.MT_DNN_ClearModelsButton.Text = 'Clear All';
            app.MT_DNN_TrainSelectedModelsButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_TrainSelectedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_TrainSelectedModelsButtonPushed, true);
            app.MT_DNN_TrainSelectedModelsButton.Enable = 'off';
            app.MT_DNN_TrainSelectedModelsButton.Text = 'Train Selection';
            app.MT_DNN_TrainAllModelsButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_TrainAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_TrainAllModelsButtonPushed, true);
            app.MT_DNN_TrainAllModelsButton.Enable = 'off';
            app.MT_DNN_TrainAllModelsButton.Text = 'Train All';
            app.MT_DNN_OptimizeHyperparametersButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_OptimizeHyperparametersButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_OptimizeHyperparametersButtonPushed, true);
            app.MT_DNN_OptimizeHyperparametersButton.Enable = 'off';
            app.MT_DNN_OptimizeHyperparametersButton.Text = 'Optimize';
            app.MT_DNN_AddAllModelsButton = uibutton(app.MT_DeepNeuralNetworksPanel, 'push');
            app.MT_DNN_AddAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_DNN_AddAllModelsButtonPushed, true);
            app.MT_DNN_AddAllModelsButton.Text = 'Add All';
            app.MT_DNN_ParallelCheckBox = uicheckbox(app.MT_DeepNeuralNetworksPanel);
            app.MT_DNN_ParallelCheckBox.Enable = 'off';
            app.MT_DNN_ParallelCheckBox.Text = 'Parallel';
            app.MT_ClassicMachineLearningPanel = uipanel(app.ModelTrainingPanel);
            app.MT_ClassicMachineLearningPanel.Title = 'Classic Machine Learning';
            app.MT_CML_ModelsListBox = uilistbox(app.MT_ClassicMachineLearningPanel);
            app.MT_CML_ModelsListBox.Items = {};
            app.MT_CML_ModelsListBox.Multiselect = 'on';
            app.MT_CML_ModelsListBox.ValueChangedFcn = createCallbackFcn(app, @MT_CML_ModelsListBoxValueChanged, true);
            app.MT_CML_ModelsListBox.Value = {};
            app.MT_CML_AddModelButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_AddModelButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_AddModelButtonPushed, true);
            app.MT_CML_AddModelButton.Text = 'Add';
            app.MT_CML_ClearModelsButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_ClearModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_ClearModelsButtonPushed, true);
            app.MT_CML_ClearModelsButton.Text = 'Clear All';
            app.MT_CML_TrainSelectedModelsButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_TrainSelectedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_TrainSelectedModelsButtonPushed, true);
            app.MT_CML_TrainSelectedModelsButton.Enable = 'off';
            app.MT_CML_TrainSelectedModelsButton.Text = 'Train Selection';
            app.MT_CML_TrainAllModelsButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_TrainAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_TrainAllModelsButtonPushed, true);
            app.MT_CML_TrainAllModelsButton.Enable = 'off';
            app.MT_CML_TrainAllModelsButton.Text = 'Train All';
            app.MT_CML_OptimizeHyperparametersButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_OptimizeHyperparametersButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_OptimizeHyperparametersButtonPushed, true);
            app.MT_CML_OptimizeHyperparametersButton.Enable = 'off';
            app.MT_CML_OptimizeHyperparametersButton.Text = 'Optimize';
            app.MT_CML_AddAllModelsButton = uibutton(app.MT_ClassicMachineLearningPanel, 'push');
            app.MT_CML_AddAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_CML_AddAllModelsButtonPushed, true);
            app.MT_CML_AddAllModelsButton.Text = 'Add All';
            app.MT_StatisticalModelsPanel = uipanel(app.ModelTrainingPanel);
            app.MT_StatisticalModelsPanel.Title = 'Statistical Models';
            app.MT_S_ModelsListBox = uilistbox(app.MT_StatisticalModelsPanel);
            app.MT_S_ModelsListBox.Items = {};
            app.MT_S_ModelsListBox.Multiselect = 'on';
            app.MT_S_ModelsListBox.ValueChangedFcn = createCallbackFcn(app, @MT_S_ModelsListBoxValueChanged, true);
            app.MT_S_ModelsListBox.Value = {};
            app.MT_S_AddModelButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_AddModelButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_AddModelButtonPushed, true);
            app.MT_S_AddModelButton.Text = 'Add';
            app.MT_S_ClearModelsButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_ClearModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_ClearModelsButtonPushed, true);
            app.MT_S_ClearModelsButton.Text = 'Clear All';
            app.MT_S_TrainSelectedModelsButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_TrainSelectedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_TrainSelectedModelsButtonPushed, true);
            app.MT_S_TrainSelectedModelsButton.Enable = 'off';
            app.MT_S_TrainSelectedModelsButton.Text = 'Train Selection';
            app.MT_S_TrainAllModelsButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_TrainAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_TrainAllModelsButtonPushed, true);
            app.MT_S_TrainAllModelsButton.Enable = 'off';
            app.MT_S_TrainAllModelsButton.Text = 'Train All';
            app.MT_S_OptimizeHyperparametersButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_OptimizeHyperparametersButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_OptimizeHyperparametersButtonPushed, true);
            app.MT_S_OptimizeHyperparametersButton.Enable = 'off';
            app.MT_S_OptimizeHyperparametersButton.Text = 'Optimize';
            app.MT_S_AddAllModelsButton = uibutton(app.MT_StatisticalModelsPanel, 'push');
            app.MT_S_AddAllModelsButton.ButtonPushedFcn = createCallbackFcn(app, @MT_S_AddAllModelsButtonPushed, true);
            app.MT_S_AddAllModelsButton.Text = 'Add All';
            app.MT_PreviewPanel = uipanel(app.ModelTrainingPanel);
            app.MT_PreviewUIAxes = uiaxes(app.MT_PreviewPanel);
            app.MT_PreviewUIAxes.XTick = [];
            app.MT_PreviewUIAxes.YTick = [];
            app.MT_PreviewUIAxes.Visible = 'off';
            app.MT_PreviewOptionsPanel = uipanel(app.MT_PreviewPanel);
            app.MT_PreviewOptionsPanel.Title = 'Options / Hyperparameters';
            app.MT_PreviewOptionsPanel.Visible = 'off';
            app.MT_PreviewNameLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewNameLabel.Text = '';
            app.MT_PreviewNameLabel_2 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewNameLabel_2.Text = '';
            app.MT_PreviewNameLabel_3 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewNameLabel_3.Text = '';
            app.MT_PreviewNameLabel_4 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewNameLabel_4.Text = '';
            app.MT_PreviewNameLabel_5 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewNameLabel_5.Text = '';
            app.MT_PreviewNameLabel_7 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewNameLabel_7.Text = '';
            app.MT_PreviewNameLabel_6 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewNameLabel_6.Text = '';
            app.MT_PreviewNameLabel_8 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewNameLabel_8.Text = '';
            app.MT_PreviewNameLabel_9 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewNameLabel_9.Text = '';
            app.MT_PreviewNameLabel_10 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewNameLabel_10.Text = '';
            app.MT_PreviewNameLabel_11 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewNameLabel_11.Text = '';
            app.MT_PreviewContentLabel = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentLabel.Text = '';
            app.MT_PreviewContentLabel_2 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentLabel_2.Text = '';
            app.MT_PreviewContentLabel_3 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentLabel_3.Text = '';
            app.MT_PreviewContentLabel_4 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentLabel_4.Text = '';
            app.MT_PreviewContentLabel_5 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentLabel_5.Text = '';
            app.MT_PreviewContentLabel_6 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentLabel_6.Text = '';
            app.MT_PreviewContentLabel_7 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentLabel_7.Text = '';
            app.MT_PreviewContentLabel_8 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentLabel_8.Text = '';
            app.MT_PreviewContentLabel_9 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentLabel_9.Text = '';
            app.MT_PreviewContentLabel_10 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentLabel_10.Text = '';
            app.MT_PreviewContentLabel_11 = uilabel(app.MT_PreviewOptionsPanel);
            app.MT_PreviewContentLabel_11.Text = '';
            app.NoModelLoadedforPreviewLabel = uilabel(app.MT_PreviewPanel);
            app.NoModelLoadedforPreviewLabel.Text = 'No Model Loaded for Preview';
            app.MT_ModelPreviewLabel = uilabel(app.ModelTrainingPanel);
            app.MT_ModelPreviewLabel.Text = 'Model Preview';
            app.MT_TrainAllButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_TrainAllButton.ButtonPushedFcn = createCallbackFcn(app, @MT_TrainAllButtonPushed, true);
            app.MT_TrainAllButton.Text = 'Train All';
            app.MT_OptimizeAllButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_OptimizeAllButton.ButtonPushedFcn = createCallbackFcn(app, @MT_OptimizeAllButtonPushed, true);
            app.MT_OptimizeAllButton.Text = 'Optimize All';
            app.MT_LoadFromFileButton = uibutton(app.ModelTrainingPanel, 'push');
            app.MT_LoadFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @MT_LoadFromFileButtonPushed, true);
            app.MT_LoadFromFileButton.Text = 'Load from File';
            app.DetectionEvaluationTab = uitab(app.MainPanel);
            app.DetectionEvaluationTab.Title = '1.4 Detection';
            app.DetectionEvaluationPanel = uipanel(app.DetectionEvaluationTab);
            app.DE_PreviousButton = uibutton(app.DetectionEvaluationPanel, 'push');
            app.DE_PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @DE_PreviousButtonPushed, true);
            app.DE_PreviousButton.Text = 'Previous';
            app.DE_NextButton = uibutton(app.DetectionEvaluationPanel, 'push');
            app.DE_NextButton.ButtonPushedFcn = createCallbackFcn(app, @DE_NextButtonPushed, true);
            app.DE_NextButton.Text = 'Next';
            app.DE_TrainedModelsPanel = uipanel(app.DetectionEvaluationPanel);
            app.DE_TrainedModelsPanel.Title = 'Models (ranked)';
            app.DE_TrainedModelsListBox = uilistbox(app.DE_TrainedModelsPanel);
            app.DE_TrainedModelsListBox.Items = {};
            app.DE_TrainedModelsListBox.Multiselect = 'on';
            app.DE_TrainedModelsListBox.Value = {};
            app.SortbyLabel = uilabel(app.DE_TrainedModelsPanel);
            app.SortbyLabel.Text = 'Rank by:';
            app.DE_SortByDropDown = uidropdown(app.DE_TrainedModelsPanel);
            app.DE_SortByDropDown.Items = {'Composite F1 Score (Static)', 'Point-wise F1 Score (Static)', 'Event-wise F1 Score (Static)', 'Point-adjusted F1 Score (Static)', 'Composite F0.5 Score (Static)', 'Point-wise F0.5 Score (Static)', 'Event-wise F0.5 Score (Static)', 'Point-adjusted F0.5 Score (Static)', 'Point-wise Precision (Static)', 'Event-wise Precision (Static)', 'Point-adjusted Precision (Static)', 'Point-wise Recall (Static)', 'Event-wise Recall (Static)', 'Point-adjusted Recall (Static)', 'Composite F1 Score (Dynamic)', 'Point-wise F1 Score (Dynamic)', 'Event-wise F1 Score (Dynamic)', 'Point-adjusted F1 Score (Dynamic)', 'Composite F0.5 Score (Dynamic)', 'Point-wise F0.5 Score (Dynamic)', 'Event-wise F0.5 Score (Dynamic)', 'Point-adjusted F0.5 Score (Dynamic)', 'Point-wise Precision (Dynamic)', 'Event-wise Precision (Dynamic)', 'Point-adjusted Precision (Dynamic)', 'Point-wise Recall (Dynamic', 'Event-wise Recall (Dynamic)', 'Point-adjusted Recall (Dynamic)'};
            app.DE_SortByDropDown.ValueChangedFcn = createCallbackFcn(app, @DE_SortByDropDownValueChanged, true);
            app.DE_SortByDropDown.Value = 'Composite F1 Score (Static)';
            app.DE_SaveSelectedModelsButton = uibutton(app.DE_TrainedModelsPanel, 'push');
            app.DE_SaveSelectedModelsButton.ButtonPushedFcn = createCallbackFcn(app, @DE_SaveSelectedModelsButtonPushed, true);
            app.DE_SaveSelectedModelsButton.Text = 'Save Selected Model(s)';
            app.DE_DetectionPanel = uipanel(app.DetectionEvaluationPanel);
            app.DE_DetectionPanel.Title = 'Detection';
            app.SelectfaultyDataLabel = uilabel(app.DE_DetectionPanel);
            app.SelectfaultyDataLabel.Text = 'Select Faulty Data:';
            app.DE_FaultyDataSelectionDropDown = uidropdown(app.DE_DetectionPanel);
            app.DE_FaultyDataSelectionDropDown.Items = {};
            app.DE_FaultyDataSelectionDropDown.Value = {};
            app.DE_RunDetectionForSelectedButton = uibutton(app.DE_DetectionPanel, 'push');
            app.DE_RunDetectionForSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DE_RunDetectionForSelectedButtonPushed, true);
            app.DE_RunDetectionForSelectedButton.Text = 'Selected Model(s)';
            app.DE_RunDetectionForLabel = uilabel(app.DE_DetectionPanel);
            app.DE_RunDetectionForLabel.Text = 'Run Detection for:';
            app.DE_RunDetectionForAllButton = uibutton(app.DE_DetectionPanel, 'push');
            app.DE_RunDetectionForAllButton.ButtonPushedFcn = createCallbackFcn(app, @DE_RunDetectionForAllButtonPushed, true);
            app.DE_RunDetectionForAllButton.Text = 'All Models';
            app.DE_DetectionShownForLabel = uilabel(app.DE_DetectionPanel);
            app.DE_DetectionShownForLabel.Text = 'Detection shown for: None';
            app.DE_DetectionTabGroup = uitabgroup(app.DE_DetectionPanel);
            app.DE_PlotsTab = uitab(app.DE_DetectionTabGroup);
            app.DE_PlotsTab.Title = 'Plots';
            app.DE_StaticThresholdingAnomalyScoresUIAxes = uiaxes(app.DE_PlotsTab);
            title(app.DE_StaticThresholdingAnomalyScoresUIAxes, 'Anomaly Scores')
            xlabel(app.DE_StaticThresholdingAnomalyScoresUIAxes, 'Timestamp')
            ylabel(app.DE_StaticThresholdingAnomalyScoresUIAxes, 'Score')
            zlabel(app.DE_StaticThresholdingAnomalyScoresUIAxes, 'Z')
            app.DE_DynamicThresholdingAnomalyScoresUIAxes = uiaxes(app.DE_PlotsTab);
            title(app.DE_DynamicThresholdingAnomalyScoresUIAxes, 'Anomaly Scores')
            xlabel(app.DE_DynamicThresholdingAnomalyScoresUIAxes, 'Timestamp')
            ylabel(app.DE_DynamicThresholdingAnomalyScoresUIAxes, 'Score')
            zlabel(app.DE_DynamicThresholdingAnomalyScoresUIAxes, 'Z')
            app.DE_DynamicThresholdingDataUIAxes = uiaxes(app.DE_PlotsTab);
            title(app.DE_DynamicThresholdingDataUIAxes, 'Data + Anomalies')
            xlabel(app.DE_DynamicThresholdingDataUIAxes, 'Timestamp')
            ylabel(app.DE_DynamicThresholdingDataUIAxes, 'Value')
            zlabel(app.DE_DynamicThresholdingDataUIAxes, 'Z')
            app.DE_StaticThresholdingDataUIAxes = uiaxes(app.DE_PlotsTab);
            title(app.DE_StaticThresholdingDataUIAxes, 'Data + Anomalies')
            xlabel(app.DE_StaticThresholdingDataUIAxes, 'Timestamp')
            ylabel(app.DE_StaticThresholdingDataUIAxes, 'Value')
            zlabel(app.DE_StaticThresholdingDataUIAxes, 'Z')
            app.DE_DynamicThresholdingLabel = uilabel(app.DE_PlotsTab);
            app.DE_DynamicThresholdingLabel.Text = 'Dynamic Thresholding';
            app.DE_StaticThresholdingLabel = uilabel(app.DE_PlotsTab);
            app.DE_StaticThresholdingLabel.Text = 'Static Thresholding';
            app.DE_StaticThresholdingUITable = uitable(app.DE_PlotsTab);
            app.DE_StaticThresholdingUITable.ColumnName = {'Metric'; 'Point-Wise'; 'Event-Wise'; 'Point-Adjusted'; 'Composite'};
            app.DE_StaticThresholdingUITable.RowName = {};
            app.AnomalyPaddingSliderLabel = uilabel(app.DE_PlotsTab);
            app.AnomalyPaddingSliderLabel.Text = {'Anomaly'; 'Padding'};
            app.DE_AnomalyPaddingSlider = uislider(app.DE_PlotsTab);
            app.DE_AnomalyPaddingSlider.Limits = [0 1000];
            app.DE_AnomalyPaddingSlider.ValueChangedFcn = createCallbackFcn(app, @DE_AnomalyPaddingSliderValueChanged, true);
            app.DE_DynamicThresholdingUITable = uitable(app.DE_PlotsTab);
            app.DE_DynamicThresholdingUITable.ColumnName = {'Metric'; 'Point-WIse'; 'Event-Wise'; 'Point-Adjusted'; 'Composite'};
            app.DE_DynamicThresholdingUITable.RowName = {};
            app.DE_WindowSizeSlider = uislider(app.DE_PlotsTab);
            app.DE_WindowSizeSlider.Limits = [0 1000];
            app.DE_WindowSizeSlider.ValueChangedFcn = createCallbackFcn(app, @DE_WindowSizeSliderValueChanged, true);
            app.WindowSizeSliderLabel = uilabel(app.DE_PlotsTab);
            app.WindowSizeSliderLabel.Text = {'Window'; 'Size'};
            app.DE_ZRangeSlider = uislider(app.DE_PlotsTab);
            app.DE_ZRangeSlider.Limits = [1 10];
            app.DE_ZRangeSlider.ValueChangedFcn = createCallbackFcn(app, @DE_ZRangeSliderValueChanged, true);
            app.DE_ZRangeSlider.Value = 1;
            app.zrangeSliderLabel = uilabel(app.DE_PlotsTab);
            app.zrangeSliderLabel.Text = 'z-range';
            app.ThresholdDropDownLabel = uilabel(app.DE_PlotsTab);
            app.ThresholdDropDownLabel.Text = 'Threshold:';
            app.DE_ThresholdDropDown = uidropdown(app.DE_PlotsTab);
            app.DE_ThresholdDropDown.Items = {};
            app.DE_ThresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @DE_ThresholdDropDownValueChanged, true);
            app.DE_ThresholdDropDown.Value = {};
            app.DE_StaticScoresLabel = uilabel(app.DE_PlotsTab);
            app.DE_StaticScoresLabel.Text = 'Scores';
            app.DE_DynamicScoresLabel = uilabel(app.DE_PlotsTab);
            app.DE_DynamicScoresLabel.Text = 'Scores';
            app.DE_TableTab = uitab(app.DE_DetectionTabGroup);
            app.DE_TableTab.Title = 'Table';
            app.DE_FullDetectionUITable = uitable(app.DE_TableTab);
            app.DE_FullDetectionUITable.ColumnName = {'Metric'};
            app.DE_FullDetectionUITable.RowName = {};
            app.DE_ExportTableButton = uibutton(app.DE_TableTab, 'push');
            app.DE_ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @DE_ExportTableButtonPushed, true);
            app.DE_ExportTableButton.Text = 'Export Table';
            app.RowwisemaximaLabel = uilabel(app.DE_TableTab);
            app.RowwisemaximaLabel.Text = 'Rowwise maxima';
            app.ColumnwisemaximaLabel = uilabel(app.DE_TableTab);
            app.ColumnwisemaximaLabel.Text = 'Columnwise maxima';
            app.LegendLabel = uilabel(app.DE_TableTab);
            app.LegendLabel.Text = 'Legend:';
            app.AutoRunTab = uitab(app.MainPanel);
            app.AutoRunTab.Title = '2. Auto Run';
            app.AutoRunPanel = uipanel(app.AutoRunTab);
            app.AR_DatasetSweepPanel = uipanel(app.AutoRunPanel);
            app.AR_DatasetSweepPanel.Title = 'Auto Training and Evaluation';
            app.AR_RunEvaluationButton = uibutton(app.AR_DatasetSweepPanel, 'push');
            app.AR_RunEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @AR_RunEvaluationButtonPushed, true);
            app.AR_RunEvaluationButton.Text = 'Run Evaluation';
            app.FractionofDatasettouseEditFieldLabel = uilabel(app.AR_DatasetSweepPanel);
            app.FractionofDatasettouseEditFieldLabel.Text = 'Fraction of Dataset to use:';
            app.AR_UseFractionEditField = uieditfield(app.AR_DatasetSweepPanel, 'numeric');
            app.AR_UseFractionEditField.Limits = [0.001 1];
            app.AR_UseFractionEditField.Value = 0.1;
            app.BestFscoreThresholdLabel = uilabel(app.AR_DatasetSweepPanel);
            app.BestFscoreThresholdLabel.Text = 'Preprocessing Method:';
            app.AR_PreprocessingMethodDropDown = uidropdown(app.AR_DatasetSweepPanel);
            app.AR_PreprocessingMethodDropDown.Items = {'Raw Data', 'Standardize', 'Rescale [0, 1]'};
            app.AR_PreprocessingMethodDropDown.Value = 'Raw Data';
            app.AR_SweepBrowseButton = uibutton(app.AR_DatasetSweepPanel, 'push');
            app.AR_SweepBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @AR_SweepBrowseButtonPushed, true);
            app.AR_SweepBrowseButton.Text = 'Browse...';
            app.AR_SweepDatasetLabel = uilabel(app.AR_DatasetSweepPanel);
            app.AR_SweepDatasetLabel.Text = 'Dataset Folder:';
            app.AR_SweepDatasetEditField = uieditfield(app.AR_DatasetSweepPanel, 'text');
            app.AR_SweepUseModelsCheckBox = uicheckbox(app.AR_DatasetSweepPanel);
            app.AR_SweepUseModelsCheckBox.Text = 'Use Configured Models from Training Tab';
            app.AR_SweepUseModelsCheckBox.Value = true;
            app.IterationsLabel_3 = uilabel(app.AR_DatasetSweepPanel);
            app.IterationsLabel_3.Text = 'Ratio Val:Test:';
            app.AR_RatioValTestEditField = uieditfield(app.AR_DatasetSweepPanel, 'numeric');
            app.AR_RatioValTestEditField.Limits = [0 1];
            app.AR_RatioValTestEditField.Value = 0.1;
            app.AR_SweepResultsUITable = uitable(app.AutoRunPanel);
            app.AR_SweepResultsUITable.ColumnName = '';
            app.AR_SweepResultsUITable.RowName = {};
            app.AR_SweepResultsLabel = uilabel(app.AutoRunPanel);
            app.AR_SweepResultsLabel.Text = 'Results:';
            app.AR_SweepExportButton = uibutton(app.AutoRunPanel, 'push');
            app.AR_SweepExportButton.ButtonPushedFcn = createCallbackFcn(app, @AR_SweepExportButtonPushed, true);
            app.AR_SweepExportButton.Text = 'Export Table';
            app.DynamicSwitchTab = uitab(app.MainPanel);
            app.DynamicSwitchTab.Title = '3. Dynamic Switch';
            app.DynamicSwitchPanel = uipanel(app.DynamicSwitchTab);
            app.DS_ResultsUITable = uitable(app.DynamicSwitchPanel);
            app.DS_ResultsUITable.ColumnName = '';
            app.DS_ResultsUITable.RowName = {};
            app.DS_ResultsLabel = uilabel(app.DynamicSwitchPanel);
            app.DS_ResultsLabel.Text = 'Results:';
            app.DS_TrainingAndEvaluationPanel = uipanel(app.DynamicSwitchPanel);
            app.DS_TrainingAndEvaluationPanel.Title = 'Training and Evaluation';
            app.DS_BrowseDatasetButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_BrowseDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DS_BrowseDatasetButtonPushed, true);
            app.DS_BrowseDatasetButton.Text = 'Browse...';
            app.DS_DatasetLabel = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.DS_DatasetLabel.Text = 'Dataset Folder:';
            app.DS_DatasetEditField = uieditfield(app.DS_TrainingAndEvaluationPanel, 'text');
            app.DS_DatasetEditField.ValueChangedFcn = createCallbackFcn(app, @DS_DatasetEditFieldValueChanged, true);
            app.DS_AutoLabelDatasetButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_AutoLabelDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DS_AutoLabelDatasetButtonPushed, true);
            app.DS_AutoLabelDatasetButton.Text = 'Auto-label Dataset';
            app.DS_RunEvaluationsButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_RunEvaluationsButton.ButtonPushedFcn = createCallbackFcn(app, @DS_RunEvaluationsButtonPushed, true);
            app.DS_RunEvaluationsButton.Text = 'Run Evaluations';
            app.DS_TrainClassifierButton = uibutton(app.DS_TrainingAndEvaluationPanel, 'push');
            app.DS_TrainClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @DS_TrainClassifierButtonPushed, true);
            app.DS_TrainClassifierButton.Text = 'Train Classifier';
            app.DS_AutoLabelLamp = uilamp(app.DS_TrainingAndEvaluationPanel);
            app.DS_TrainLamp = uilamp(app.DS_TrainingAndEvaluationPanel);
            app.DS_EvaluationLamp = uilamp(app.DS_TrainingAndEvaluationPanel);
            app.BestFscoreThresholdLabel_2 = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.BestFscoreThresholdLabel_2.Text = 'Preprocessing Method:';
            app.DS_PreprocessingMethodDropDown = uidropdown(app.DS_TrainingAndEvaluationPanel);
            app.DS_PreprocessingMethodDropDown.Items = {'Raw Data', 'Standardize', 'Rescale [0, 1]'};
            app.DS_PreprocessingMethodDropDown.Value = 'Raw Data';
            app.BestFscoreThresholdLabel_3 = uilabel(app.DS_TrainingAndEvaluationPanel);
            app.BestFscoreThresholdLabel_3.Text = 'Score for Auto-labeling:';
            app.DS_ScoreDropDown = uidropdown(app.DS_TrainingAndEvaluationPanel);
            app.DS_ScoreDropDown.Items = {'Composite F1 Score', 'Point-wise F1 Score', 'Event-wise F1 Score', 'Point-Adjusted F1 Score'};
            app.DS_ScoreDropDown.Value = 'Composite F1 Score';
            app.DS_UseModelsCheckBox = uicheckbox(app.DS_TrainingAndEvaluationPanel);
            app.DS_UseModelsCheckBox.Text = 'Use Configured Models from Training Tab';
            app.DS_UseModelsCheckBox.Value = true;
            app.DS_ExportButton = uibutton(app.DynamicSwitchPanel, 'push');
            app.DS_ExportButton.ButtonPushedFcn = createCallbackFcn(app, @DS_ExportButtonPushed, true);
            app.DS_ExportButton.Text = 'Export Table';
            app.MT_DNN_ContextMenu = uicontextmenu(app.UIFigure);
            app.MT_DNN_DeleteMenu = uimenu(app.MT_DNN_ContextMenu);
            app.MT_DNN_DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @MT_DNN_DeleteMenuSelected, true);
            app.MT_DNN_DeleteMenu.Text = 'Delete';
            app.MT_DNN_ShowModelParametersMenu = uimenu(app.MT_DNN_ContextMenu);
            app.MT_DNN_ShowModelParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @MT_DNN_ShowModelParametersMenuSelected, true);
            app.MT_DNN_ShowModelParametersMenu.Text = 'Show Model Parameters';
            % Assign app.MT_DNN_ContextMenu
            app.MT_DNN_ModelsListBox.ContextMenu = app.MT_DNN_ContextMenu;
            app.MT_CML_ContextMenu = uicontextmenu(app.UIFigure);
            app.MT_CML_DeleteMenu = uimenu(app.MT_CML_ContextMenu);
            app.MT_CML_DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @MT_CML_DeleteMenuSelected, true);
            app.MT_CML_DeleteMenu.Text = 'Delete';
            app.MT_CML_ShowModelParametersMenu = uimenu(app.MT_CML_ContextMenu);
            app.MT_CML_ShowModelParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @MT_CML_ShowModelParametersMenuSelected, true);
            app.MT_CML_ShowModelParametersMenu.Text = 'Show Model Parameters';
            % Assign app.MT_CML_ContextMenu
            app.MT_CML_ModelsListBox.ContextMenu = app.MT_CML_ContextMenu;
            app.MT_S_ContextMenu = uicontextmenu(app.UIFigure);
            app.MT_S_DeleteMenu = uimenu(app.MT_S_ContextMenu);
            app.MT_S_DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @MT_S_DeleteMenuSelected, true);
            app.MT_S_DeleteMenu.Text = 'Delete';
            app.MT_S_ShowModelParametersMenu = uimenu(app.MT_S_ContextMenu);
            app.MT_S_ShowModelParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @MT_S_ShowModelParametersMenuSelected, true);
            app.MT_S_ShowModelParametersMenu.Text = 'Show Model Parameters';
            % Assign app.MT_S_ContextMenu
            app.MT_S_ModelsListBox.ContextMenu = app.MT_S_ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TSADPlatform

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end